classdef App_Principal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        Container_Botones               matlab.ui.container.GridLayout
        B_EnergiaProducida_3            matlab.ui.control.Button
        B_Instalacion                   matlab.ui.control.Button
        B_CalculadoraConsumo_1          matlab.ui.control.Button
        P_Central                       matlab.ui.container.Panel
        P_Central_Solucion_Hibrido      matlab.ui.container.Panel
        P_Mostrar_Hibrido               matlab.ui.container.Panel
        SistemaHbridoLabel_2            matlab.ui.control.Label
        P_Panel_2                       matlab.ui.container.Panel
        Fi_Tiempo_Vida_Inversor         matlab.ui.control.NumericEditField
        TIEMPODELabel_2                 matlab.ui.control.Label
        Fi_Costo_Inversor               matlab.ui.control.NumericEditField
        VOLTAJELabel_2                  matlab.ui.control.Label
        Fi_VAdmisible_Inversor          matlab.ui.control.NumericEditField
        ANCHOLabel_2                    matlab.ui.control.Label
        Fi_Potencia_Inversor            matlab.ui.control.NumericEditField
        POTENCIAEditFieldLabel_2        matlab.ui.control.Label
        Fi_Modelo_Inversor              matlab.ui.control.EditField
        MODELOEditFieldLabel_3          matlab.ui.control.Label
        INVERSORLabel_2                 matlab.ui.control.Label
        I_Imagen_Inversor               matlab.ui.control.Image
        P_Panel                         matlab.ui.container.Panel
        Fi_Tiempo_Vida_Panel            matlab.ui.control.NumericEditField
        TIEMPODELabel                   matlab.ui.control.Label
        Fi_Alto_Panel                   matlab.ui.control.NumericEditField
        ALTOLabel                       matlab.ui.control.Label
        Fi_Voltaje_Panel                matlab.ui.control.NumericEditField
        VOLTAJELabel                    matlab.ui.control.Label
        Fi_Costo_Panel                  matlab.ui.control.NumericEditField
        COSTOLabel                      matlab.ui.control.Label
        Fi_Ancho_Panel                  matlab.ui.control.NumericEditField
        ANCHOLabel                      matlab.ui.control.Label
        Fi_Potencia_Panel               matlab.ui.control.NumericEditField
        POTENCIAEditFieldLabel          matlab.ui.control.Label
        Fi_Modelo_Panel                 matlab.ui.control.EditField
        MODELOEditFieldLabel_2          matlab.ui.control.Label
        PANELLabel_2                    matlab.ui.control.Label
        I_Imagen_Panel                  matlab.ui.control.Image
        P_Solucion_Hibrido              matlab.ui.container.Panel
        SistemaHbridoLabel              matlab.ui.control.Label
        Fi_Costo_Total_Solucion         matlab.ui.control.NumericEditField
        COSTOTOTALLabel                 matlab.ui.control.Label
        Fi_Costo_Mantenimiento_Solucion  matlab.ui.control.NumericEditField
        COSTOMANTENIMIENTOLabel         matlab.ui.control.Label
        Fi_Costo_Compra_Solucion        matlab.ui.control.NumericEditField
        COSTOCOMPRAEditFieldLabel       matlab.ui.control.Label
        Fi_Costo_Instalacion_Solucion   matlab.ui.control.NumericEditField
        COSTOINSTALACIONEditFieldLabel  matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        NumInversorH                    matlab.ui.control.NumericEditField
        MODELOEditField_2               matlab.ui.control.EditField
        MODELOEditField_2Label          matlab.ui.control.Label
        INVERSORLabel                   matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        NumPanelH                       matlab.ui.control.NumericEditField
        MODELOEditField                 matlab.ui.control.EditField
        MODELOEditFieldLabel            matlab.ui.control.Label
        PANELLabel                      matlab.ui.control.Label
        I_Imagen_Solucion               matlab.ui.control.Image
        P_Central_Solucion              matlab.ui.container.Panel
        P_Mostrar                       matlab.ui.container.Panel
        P_Panel_4                       matlab.ui.container.Panel
        Fi_Tiempo_Vida_Inversor_2       matlab.ui.control.NumericEditField
        TIEMPODELabel_5                 matlab.ui.control.Label
        Fi_Costo_Inversor_2             matlab.ui.control.NumericEditField
        VOLTAJELabel_5                  matlab.ui.control.Label
        Fi_VAdmisible_Inversor_2        matlab.ui.control.NumericEditField
        ANCHOLabel_5                    matlab.ui.control.Label
        Fi_Potencia_Inversor_2          matlab.ui.control.NumericEditField
        POTENCIAEditFieldLabel_4        matlab.ui.control.Label
        Fi_Modelo_Inversor_2            matlab.ui.control.EditField
        MODELOEditFieldLabel_6          matlab.ui.control.Label
        INVERSORLabel_4                 matlab.ui.control.Label
        I_Imagen_Inversor_2             matlab.ui.control.Image
        P_Bateria                       matlab.ui.container.Panel
        Fi_Voltaje_Bateria              matlab.ui.control.NumericEditField
        TIEMPODELabel_4                 matlab.ui.control.Label
        Fi_Alto_Bateria                 matlab.ui.control.NumericEditField
        ALTOLabel_3                     matlab.ui.control.Label
        Fi_Largo_Bateria                matlab.ui.control.NumericEditField
        VOLTAJELabel_4                  matlab.ui.control.Label
        Fi_Costo_Bateria                matlab.ui.control.NumericEditField
        COSTOLabel_3                    matlab.ui.control.Label
        Fi_Ancho_Bateria                matlab.ui.control.NumericEditField
        ANCHOLabel_4                    matlab.ui.control.Label
        Fi_Capacidad_Bateria            matlab.ui.control.NumericEditField
        CAPACIDADLabel                  matlab.ui.control.Label
        Fi_Modelo_Bateria               matlab.ui.control.EditField
        MODELOEditFieldLabel_5          matlab.ui.control.Label
        BATERIALabel_2                  matlab.ui.control.Label
        I_Imagen_Bateria                matlab.ui.control.Image
        P_Panel_3                       matlab.ui.container.Panel
        Fi_Tiempo_Vida_Panel_2          matlab.ui.control.NumericEditField
        TIEMPODELabel_3                 matlab.ui.control.Label
        Fi_Alto_Panel_2                 matlab.ui.control.NumericEditField
        ALTOLabel_2                     matlab.ui.control.Label
        Fi_Voltaje_Panel_2              matlab.ui.control.NumericEditField
        VOLTAJELabel_3                  matlab.ui.control.Label
        Fi_Costo_Panel_2                matlab.ui.control.NumericEditField
        COSTOLabel_2                    matlab.ui.control.Label
        Fi_Ancho_Panel_2                matlab.ui.control.NumericEditField
        ANCHOLabel_3                    matlab.ui.control.Label
        Fi_Potencia_Panel_2             matlab.ui.control.NumericEditField
        POTENCIAEditFieldLabel_3        matlab.ui.control.Label
        Fi_Modelo_Panel_2               matlab.ui.control.EditField
        MODELOEditFieldLabel_4          matlab.ui.control.Label
        PANELLabel_4                    matlab.ui.control.Label
        I_Imagen_Panel_2                matlab.ui.control.Image
        P_Solucion                      matlab.ui.container.Panel
        I_Imagen_Solucion_2             matlab.ui.control.Image
        Fi_Costo_Total_Solucion_2       matlab.ui.control.NumericEditField
        COSTOTOTALLabel_2               matlab.ui.control.Label
        Fi_Costo_Mantenimiento_Solucion_2  matlab.ui.control.NumericEditField
        COSTOMANTENIMIENTOLabel_2       matlab.ui.control.Label
        Fi_Costo_Compra_Solucion_2      matlab.ui.control.NumericEditField
        COSTOCOMPRAEditFieldLabel_2     matlab.ui.control.Label
        Fi_Costo_Instalacion_Solucion_2  matlab.ui.control.NumericEditField
        COSTOINSTALACIONEditFieldLabel_2  matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        NumInversor                     matlab.ui.control.NumericEditField
        MODELOEditField_5               matlab.ui.control.EditField
        MODELOEditField_5Label          matlab.ui.control.Label
        INVERSORLabel_3                 matlab.ui.control.Label
        Panel_5                         matlab.ui.container.Panel
        NumBateria                      matlab.ui.control.NumericEditField
        MODELOEditField_4               matlab.ui.control.EditField
        MODELOEditField_4Label          matlab.ui.control.Label
        BATERIALabel                    matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        NumPanel                        matlab.ui.control.NumericEditField
        MODELOEditField_3               matlab.ui.control.EditField
        MODELOEditField_3Label          matlab.ui.control.Label
        PANELLabel_3                    matlab.ui.control.Label
        P_inicio_Instalacion            matlab.ui.container.Panel
        B_System_YY                     matlab.ui.control.Button
        B_System_Hibrido                matlab.ui.control.Button
        P_Central_Recibos               matlab.ui.container.Panel
        P_TablaRecibo_Consumo_1         matlab.ui.container.Panel
        D_Fecha_Recibo                  matlab.ui.control.DatePicker
        FechaReciboDatePickerLabel      matlab.ui.control.Label
        Fi_ReciboCostoTotal_1           matlab.ui.control.NumericEditField
        CostoTotalEditField_2Label      matlab.ui.control.Label
        Fi_ReciboConsumo_1              matlab.ui.control.NumericEditField
        ConsumomensualEditFieldLabel    matlab.ui.control.Label
        I_Recibo                        matlab.ui.control.Image
        L_Recibo                        matlab.ui.control.Label
        B_ActualizarRecibo_1            matlab.ui.control.Button
        B_EliminarRecibo_1              matlab.ui.control.Button
        B_EditarRecibo_1                matlab.ui.control.Button
        T_Recibo_1                      matlab.ui.control.Table
        G_Estadistica                   matlab.ui.container.Panel
        A_Year_Estadistica              matlab.ui.control.DropDown
        RecibosButton                   matlab.ui.control.Button
        ElectrodomesticosButton         matlab.ui.control.Button
        G_Estadistica_Recibo            matlab.ui.control.UIAxes
        P_IngresarRecibo_1              matlab.ui.container.Panel
        Fi_ConsumoMesA_IngresarR_1      matlab.ui.control.DatePicker
        FechaReciboLabel                matlab.ui.control.Label
        Fi_CostoTotal_IngresarR_1       matlab.ui.control.NumericEditField
        CostoTotalEditFieldLabel        matlab.ui.control.Label
        Fi_ConsumoMensual_IngresarR_1   matlab.ui.control.NumericEditField
        ConsumoMensualEditFieldLabel_2  matlab.ui.control.Label
        IngreselosdatosdesureciboLabel  matlab.ui.control.Label
        B_Ingresar_IngresarRecibo_1     matlab.ui.control.Button
        B_Regresar_IngresarRecibo_1     matlab.ui.control.Button
        P_inicio_1                      matlab.ui.container.Panel
        B_Tendencias_1                  matlab.ui.control.Button
        B_Ingresar_Electrodomestcos_1   matlab.ui.control.Button
        B_Ingresar_Recibos_1            matlab.ui.control.Button
        P_Central_Electrodomesticos     matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        P_Electrodomesticos             matlab.ui.container.Panel
        P_CalcularConsumo_IngresarE_1   matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ConsumoMensualTab               matlab.ui.container.Tab
        Ln_Potencia_Pico                matlab.ui.control.NumericEditField
        PotenciaPicoEditFieldLabel      matlab.ui.control.Label
        Ln_Consumo_Mensual_Promedio     matlab.ui.control.NumericEditField
        ConsumoMensualPromedioEditFieldLabel  matlab.ui.control.Label
        Ln_Consumo_Mensual              matlab.ui.control.NumericEditField
        ConsumoMensualEditFieldLabel    matlab.ui.control.Label
        ConsumoRelativoTab              matlab.ui.container.Tab
        Ln_Potencia_Pico_Relativa       matlab.ui.control.NumericEditField
        PotenciaPicoRelativaEditFieldLabel  matlab.ui.control.Label
        Ln_Consumo_Relativo_Promedio    matlab.ui.control.NumericEditField
        ConsumoRelativoPromedioLabel    matlab.ui.control.Label
        Ln_Consumo_Relativo             matlab.ui.control.NumericEditField
        ConsumoRelativoEditFieldLabel   matlab.ui.control.Label
        P_Anadir_IngresarE_1            matlab.ui.container.Panel
        Image9                          matlab.ui.control.Image
        UnidadesLabel                   matlab.ui.control.Label
        Fi_Cantidad_IngresarE_1         matlab.ui.control.NumericEditField
        CantidadEditFieldLabel          matlab.ui.control.Label
        B_Regresar_IngresarE            matlab.ui.control.Button
        D_Ambiente_IngresarE_1          matlab.ui.control.DropDown
        AmbienteDropDownLabel           matlab.ui.control.Label
        HorasLabel_4                    matlab.ui.control.Label
        Fi_HorasNoche_1_IngresarE_1     matlab.ui.control.NumericEditField
        UsopordaLabel_2                 matlab.ui.control.Label
        DiasLabel                       matlab.ui.control.Label
        UsoporsemanaLabel               matlab.ui.control.Label
        Fi_Uso_Semana_IngresarE_1       matlab.ui.control.NumericEditField
        D_Electrodomesticos_IngresarE_1  matlab.ui.control.ListBox
        HorasLabel_3                    matlab.ui.control.Label
        WattsLabel                      matlab.ui.control.Label
        Fi_HorasDias_IngresarE_1        matlab.ui.control.NumericEditField
        UsopordaLabel                   matlab.ui.control.Label
        Fi_PotenciaEnWatts_IngresarE_1  matlab.ui.control.NumericEditField
        PotenciaLabel_2                 matlab.ui.control.Label
        Fi_Nombre_anadir_IngresarE_1    matlab.ui.control.EditField
        NombreEditFieldLabel            matlab.ui.control.Label
        D_Tipo_anadir_IngresarE_1       matlab.ui.control.DropDown
        TipoDropDown_2Label             matlab.ui.control.Label
        B_Agregar_anadir_IngresarE      matlab.ui.control.Button
        P_ModificarElectrodomesticos_IngresarE_1  matlab.ui.container.Panel
        Fi_Unidad_Electrodomestico      matlab.ui.control.NumericEditField
        UEditFieldLabel                 matlab.ui.control.Label
        B_Eliminar_Electrodomestico     matlab.ui.control.Button
        B_Actualizar_Electrodomestico   matlab.ui.control.Button
        B_Editar_Electrodomestico       matlab.ui.control.Button
        Fi_Horas_ModificarE_2           matlab.ui.control.NumericEditField
        HorasLabel_2                    matlab.ui.control.Label
        NombreLabel                     matlab.ui.control.Label
        T_Electrodomesticos_Gestionar_1  matlab.ui.control.Table
        Fi_Dias_ModificarE_1            matlab.ui.control.NumericEditField
        DasLabel                        matlab.ui.control.Label
        Fi_Horas_ModificarE_1           matlab.ui.control.NumericEditField
        HorasLabel                      matlab.ui.control.Label
        D_Tipo_ModificarE_1             matlab.ui.control.DropDown
        TipoDropDownLabel               matlab.ui.control.Label
        Fi_Modificar_ModificarE_1       matlab.ui.control.NumericEditField
        PotenciaLabel                   matlab.ui.control.Label
        B_LimpiarCampos_ModificarE      matlab.ui.control.Button
        Image8                          matlab.ui.control.Image
        B_BuscarElectrodomestico_ModificarE  matlab.ui.control.Button
        ElectrodomsticoLabel            matlab.ui.control.Label
        Image7                          matlab.ui.control.Image
        P_Central_Tendencias            matlab.ui.container.Panel
        G_Graficos_Tendencia            matlab.ui.container.GridLayout
        P_Tendencias_1                  matlab.ui.container.Panel
        P_Tendencias_Vinculos           matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        B_EditarE_Tendencia_1           matlab.ui.control.Button
        B_EditarRec_Tendencias          matlab.ui.control.Button
        B_AnadirRec_Tendencias          matlab.ui.control.Button
        B_AnadirE_Tendencias            matlab.ui.control.Button
        P_TendenciaCostoMeses_1         matlab.ui.container.Panel
        TA_DescripcionAnalisisCostoMeses_Tendencias_1  matlab.ui.control.TextArea
        G_CostoMeses_Tendencias_1       matlab.ui.control.UIAxes
        P_TendenciaAnalisisRadTemp_1    matlab.ui.container.Panel
        B_Tendencia_CambiarGraf_1       matlab.ui.control.StateButton
        TA_DescripcionAnalisisRadTemp_Tendencias_1  matlab.ui.control.TextArea
        G_RadTemp_Tendencias_2          matlab.ui.control.UIAxes
        G_RadTemp_Tendencias_1          matlab.ui.control.UIAxes
        P_TendenciaProduccion_1         matlab.ui.container.Panel
        TA_DescripcionAnalisisPvsT_Tendencias  matlab.ui.control.TextArea
        G_PvsT_Tendencias_1             matlab.ui.control.UIAxes
        AnlisisdeDatosLabel             matlab.ui.control.Label
        P_Botones                       matlab.ui.container.Panel
        P_Botones_Instalacion           matlab.ui.container.Panel
        GridLayout_3                    matlab.ui.container.GridLayout
        B_Solucion_2                    matlab.ui.control.Button
        B_Mostrar_2                     matlab.ui.control.Button
        P_Botones_Instalacion_Hibrido   matlab.ui.container.Panel
        GridLayout_2                    matlab.ui.container.GridLayout
        B_Solucion                      matlab.ui.control.Button
        B_Mostrar                       matlab.ui.control.Button
        P_Botones_Recibo_1              matlab.ui.container.Panel
        G_Botones_Tendencia_2           matlab.ui.container.GridLayout
        B_ReciboGestionar_1             matlab.ui.control.Button
        B_ReciboAnadir_1                matlab.ui.control.Button
        P_Botones_CalculadoraConsumo_1  matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        B_ModificarElectrodomesticos_IngresarE  matlab.ui.control.Button
        B_CalcularConsumo_IngresarE     matlab.ui.control.Button
        B_Anadir_IngresarE              matlab.ui.control.Button
        P_Botones_Tendencias_1          matlab.ui.container.Panel
        G_Botones_Tendencia             matlab.ui.container.GridLayout
        B_TendenciasConsumo             matlab.ui.control.Button
        B_DatosClimaticos               matlab.ui.control.Button
        B_TendenciasCostoKWH            matlab.ui.control.Button
    end

    
    properties (Access = public)
                Usuario
                M1
                M2
                M3
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Usuario = C_Usuario(1, getenv('USERNAME'), "Arequipa");
            app.M1 = M1_Calculadora_Consumo(app.Usuario);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            Carga_BD(app);
            delete(app)
        end

        % Button pushed function: B_TendenciasCostoKWH
        function B_Tendencias_Consumo_View(app, event)
            FC_showView(app,"1.3.2")
            GraficoConsumoTendencia(app)
        end

        % Button pushed function: B_TendenciasConsumo
        function B_Tendencias_DatosClimaticos_View(app, event)
            FC_showView(app,"1.3.4")
            plotRadiacionTemperatura(app)
        end

        % Button pushed function: B_DatosClimaticos
        function B_Tendencias_CostoKWH_View(app, event)
            FC_showView(app,"1.3.3")
            GraficoCostoKWH(app)
        end

        % Button pushed function: B_EditarRec_Tendencias
        function B_Tendencias_ModificarRec_View(app, event)
            FC_showView(app,"1.1.4")
        end

        % Button pushed function: B_AnadirRec_Tendencias
        function B_Tendencias_AnadirRec_View(app, event)
            FC_showView(app,"1.1.3")
        end

        % Button pushed function: B_EditarE_Tendencia_1
        function B_Tendencias_ModificarE_View(app, event)
            FC_showView(app,"1.2.5")
        end

        % Button pushed function: B_AnadirE_Tendencias
        function B_Tendencias_AnadirE_View(app, event)
            FC_showView(app,"1.2.1")
        end

        % Button pushed function: B_Tendencias_1
        function B_Tendencias_1_View(app, event)
            FC_showView(app,"1.3.1")
        end

        % Button pushed function: B_CalculadoraConsumo_1
        function CalculadoraConsumoView_1(app, event)
            app.G_Estadistica.Visible = "off";
            FC_showView(app,"1")
        end

        % Value changed function: B_Tendencia_CambiarGraf_1
        function B_Tendencia_CambiarGraf_1_1ValueChanged(app, event)
            value = app.B_Tendencia_CambiarGraf_1.Value;
            switchGraphVisibility(app, app.G_RadTemp_Tendencias_1, app.G_RadTemp_Tendencias_2, value);
        end

        % Button pushed function: B_Ingresar_Electrodomestcos_1
        function IngresarElectrodomesticosView(app, event)
            FC_showView(app,"1.2.1")
            Button_Ingresar_Electrodomesticos(app);
        end

        % Value changed function: D_Ambiente_IngresarE_1
        function D_Ambiente_IngresarE_1ValueChanged(app, event)
            Seleccion_Ambiente(app)
        end

        % Clicked callback: D_Electrodomesticos_IngresarE_1
        function D_Electrodomesticos_IngresarE_1Clicked(app, event)
            Seleccion_Electrodomestico(app);
        end

        % Button pushed function: B_Agregar_anadir_IngresarE
        function B_Agregar_anadir_IngresarE_1ButtonPushed(app, event)
            Agregar_Electrodomestico(app);
            app.T_Electrodomesticos_Gestionar_1.Data = app.M1.Table_Electrodomesticos();
        end

        % Button pushed function: B_Regresar_IngresarE
        function RegresarAnadirElectrodomesticoView(app, event)
            FC_showView(app,"1.1.2");
        end

        % Button pushed function: B_CalcularConsumo_IngresarE
        function CalcularConsumoE_IngresarE_1_View(app, event)
            FC_showView(app,"1.2.3")
            Calcular_Consumo(app);
        end

        % Button pushed function: B_ModificarElectrodomesticos_IngresarE
        function ModificarE_IngresarE_1(app, event)
            FC_showView(app,"1.2.4")
            app.T_Electrodomesticos_Gestionar_1.Data = app.M1.Table_Electrodomesticos();
        end

        % Button pushed function: B_Anadir_IngresarE
        function AnadirE_IngresarE_1_View(app, event)
            FC_showView(app,"1.2.1");
        end

        % Cell selection callback: T_Electrodomesticos_Gestionar_1
        function MostrarDatos_1Fila(app, event)
            Seleccion_Electrodomestico_Tabla(app);
        end

        % Button pushed function: B_Editar_Electrodomestico
        function B_Editar_ElectrodomesticoButtonPushed(app, event)
             app.B_Editar_Electrodomestico.Visible = "off";
            app.B_Actualizar_Electrodomestico.Visible = "on";
            app.B_Eliminar_Electrodomestico.Visible = "on";
            app.Fi_Modificar_ModificarE_1.Editable = "on";
            app.Fi_Dias_ModificarE_1.Editable = "on";
            app.Fi_Horas_ModificarE_1.Editable = "on";
            app.Fi_Horas_ModificarE_2.Editable = "on";
            app.Fi_Unidad_Electrodomestico.Editable = "on";
        end

        % Button pushed function: B_Actualizar_Electrodomestico
        function B_Actualizar_ElectrodomesticoButtonPushed(app, event)
            Update_Electrodomestico(app);
        end

        % Button pushed function: B_Eliminar_Electrodomestico
        function B_Eliminar_ElectrodomesticoButtonPushed(app, event)
            Eliminar_Electrodomestico(app);
        end

        % Button pushed function: B_Ingresar_Recibos_1
        function IngresarRecibosView_1(app, event)
            FC_showView(app,"1.1");
        end

        % Button pushed function: B_Ingresar_IngresarRecibo_1
        function B_Ingresar_IngresarRecibo_1_1ButtonPushed(app, event)
            Ingresar_Recibo(app);
        end

        % Button pushed function: B_Regresar_IngresarRecibo_1
        function RegresarReciboElectrodomesticoview(app, event)
            FC_showView(app,"1.1.2");
        end

        % Button pushed function: B_ReciboAnadir_1
        function ReciboAnadirView(app, event)
            app.G_Estadistica.Visible = "off";
            FC_showView(app,"1.1.3");
        end

        % Button pushed function: B_ReciboGestionar_1
        function ReciboGestionarView(app, event)
            app.G_Estadistica.Visible = "off";
            FC_showView(app,"1.1.4")
            Tabla_Recibos(app);
        end

        % Selection changed function: T_Recibo_1
        function T_Recibo_1SelectionChanged(app, event)
            Seleccion_Recibo_Tabla(app);
        end

        % Image clicked function: I_Recibo
        function I_ReciboImageClicked(app, event)
            Grafica_Recibos(app);
        end

        % Button pushed function: B_EditarRecibo_1
        function B_EditarRecibo_1ButtonPushed(app, event)
            app.Fi_ReciboCostoTotal_1.Editable ="on";
            app.Fi_ReciboConsumo_1.Editable = "on";
            app.B_EditarRecibo_1.Visible = "off";
            app.B_EliminarRecibo_1.Visible = "on";
            app.B_ActualizarRecibo_1.Visible = "on";
        end

        % Button pushed function: B_ActualizarRecibo_1
        function B_ActualizarRecibo_1ButtonPushed(app, event)
            Update_Recibo(app);
        end

        % Button pushed function: B_EliminarRecibo_1
        function B_EliminarRecibo_1ButtonPushed(app, event)
            Eliminar_Recibo(app);
        end

        % Button pushed function: B_Mostrar
        function B_MostrarButtonPushed(app, event)
            app.P_Solucion_Hibrido.Visible = "off";
            app.P_Mostrar_Hibrido.Visible = "on";
        end

        % Button pushed function: B_Solucion
        function B_SolucionButtonPushed(app, event)
            app.P_Solucion_Hibrido.Visible = "on";
            app.P_Mostrar_Hibrido.Visible = "off";
        end

        % Button pushed function: B_Instalacion
        function B_InstalacionButtonPushed(app, event)
            app.P_Botones.Visible = "on";
            app.P_inicio_Instalacion.Visible = "on";
            app.P_Botones_Instalacion.Visible = "off";
            app.P_Botones_Instalacion_Hibrido.Visible = "off";
            app.P_Solucion_Hibrido.Visible = "off";
            app.P_Mostrar_Hibrido.Visible = "off";
            app.P_Central_Solucion.Visible = "off";
            app.P_Central_Solucion_Hibrido.Visible = "off";
        end

        % Button pushed function: B_System_YY
        function B_System_YYButtonPushed(app, event)
            app.P_Botones.Visible = "on";
            app.P_Botones_Instalacion.Visible = "on";
            app.P_Central_Solucion.Visible = "on";
            app.P_Mostrar.Visible = "on";
            app.P_inicio_Instalacion.Visible = "off";
            B_SolucionButtonPushed(app, event);
         
            M2_MostrarCostos(app);

            %Para boton Mostrar
            %PANEL
            M2_MostrarModeloPanel(app);
            
            %BATERIA
            M2_MostrarModeloBateria(app);

            %INVERSOR
            M2_MostrarModeloInversor(app);
        end

        % Button pushed function: B_System_Hibrido
        function B_System_HibridoButtonPushed(app, event)
            app.P_Botones_Instalacion_Hibrido.Visible = "on";
            app.P_Central_Solucion_Hibrido.Visible = "on";
            app.P_Mostrar_Hibrido.Visible = "on";
            app.P_inicio_Instalacion.Visible = "off";

            B_Solucion_2ButtonPushed(app, event);
            
            M2_MostrarCostos(app);
            
            %PANEL
            M2_MostrarModeloPanel(app);

            %BATERIA
            M2_MostrarModeloBateria(app);
        end

        % Button pushed function: B_Solucion_2
        function B_Solucion_2ButtonPushed(app, event)
            app.P_Solucion.Visible ="on";
            app.P_Mostrar.Visible = "off";
        end

        % Button pushed function: B_Mostrar_2
        function B_Mostrar_2ButtonPushed(app, event)
            app.P_Solucion.Visible ="off";
            app.P_Mostrar.Visible = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.P_Botones = uipanel(app.UIFigure);
            app.P_Botones_Tendencias_1 = uipanel(app.P_Botones);
            app.P_Botones_Tendencias_1.Visible = 'off';
            app.G_Botones_Tendencia = uigridlayout(app.P_Botones_Tendencias_1);
            app.B_TendenciasCostoKWH = uibutton(app.G_Botones_Tendencia, 'push');
            app.B_TendenciasCostoKWH.ButtonPushedFcn = createCallbackFcn(app, @B_Tendencias_Consumo_View, true);
            app.B_TendenciasCostoKWH.Text = 'Consumo';
            app.B_DatosClimaticos = uibutton(app.G_Botones_Tendencia, 'push');
            app.B_DatosClimaticos.ButtonPushedFcn = createCallbackFcn(app, @B_Tendencias_CostoKWH_View, true);
            app.B_DatosClimaticos.Text = 'Costo KWH';
            app.B_TendenciasConsumo = uibutton(app.G_Botones_Tendencia, 'push');
            app.B_TendenciasConsumo.ButtonPushedFcn = createCallbackFcn(app, @B_Tendencias_DatosClimaticos_View, true);
            app.B_TendenciasConsumo.Text = 'Datos climáticos';
            app.P_Botones_CalculadoraConsumo_1 = uipanel(app.P_Botones);
            app.P_Botones_CalculadoraConsumo_1.Visible = 'off';
            app.GridLayout2 = uigridlayout(app.P_Botones_CalculadoraConsumo_1);
            app.B_Anadir_IngresarE = uibutton(app.GridLayout2, 'push');
            app.B_Anadir_IngresarE.ButtonPushedFcn = createCallbackFcn(app, @AnadirE_IngresarE_1_View, true);
            app.B_Anadir_IngresarE.Text = 'Añadir';
            app.B_CalcularConsumo_IngresarE = uibutton(app.GridLayout2, 'push');
            app.B_CalcularConsumo_IngresarE.ButtonPushedFcn = createCallbackFcn(app, @CalcularConsumoE_IngresarE_1_View, true);
            app.B_CalcularConsumo_IngresarE.Text = {'Calcular '; 'Consumo'};
            app.B_ModificarElectrodomesticos_IngresarE = uibutton(app.GridLayout2, 'push');
            app.B_ModificarElectrodomesticos_IngresarE.ButtonPushedFcn = createCallbackFcn(app, @ModificarE_IngresarE_1, true);
            app.B_ModificarElectrodomesticos_IngresarE.Text = {'Modificar'; 'Electrodomésticos'};
            app.P_Botones_Recibo_1 = uipanel(app.P_Botones);
            app.P_Botones_Recibo_1.Visible = 'off';
            app.G_Botones_Tendencia_2 = uigridlayout(app.P_Botones_Recibo_1);
            app.B_ReciboAnadir_1 = uibutton(app.G_Botones_Tendencia_2, 'push');
            app.B_ReciboAnadir_1.ButtonPushedFcn = createCallbackFcn(app, @ReciboAnadirView, true);
            app.B_ReciboAnadir_1.Text = 'Añadir recibo';
            app.B_ReciboGestionar_1 = uibutton(app.G_Botones_Tendencia_2, 'push');
            app.B_ReciboGestionar_1.ButtonPushedFcn = createCallbackFcn(app, @ReciboGestionarView, true);
            app.B_ReciboGestionar_1.Text = 'Gestionar recibos';
            app.P_Botones_Instalacion_Hibrido = uipanel(app.P_Botones);
            app.P_Botones_Instalacion_Hibrido.Visible = 'off';
            app.GridLayout_2 = uigridlayout(app.P_Botones_Instalacion_Hibrido);
            app.B_Mostrar = uibutton(app.GridLayout_2, 'push');
            app.B_Mostrar.ButtonPushedFcn = createCallbackFcn(app, @B_MostrarButtonPushed, true);
            app.B_Mostrar.Text = 'MOSTRAR';
            app.B_Solucion = uibutton(app.GridLayout_2, 'push');
            app.B_Solucion.ButtonPushedFcn = createCallbackFcn(app, @B_SolucionButtonPushed, true);
            app.B_Solucion.Text = 'SOLUCION';
            app.P_Botones_Instalacion = uipanel(app.P_Botones);
            app.P_Botones_Instalacion.Visible = 'off';
            app.GridLayout_3 = uigridlayout(app.P_Botones_Instalacion);
            app.B_Mostrar_2 = uibutton(app.GridLayout_3, 'push');
            app.B_Mostrar_2.ButtonPushedFcn = createCallbackFcn(app, @B_Mostrar_2ButtonPushed, true);
            app.B_Mostrar_2.Text = 'MOSTRAR';
            app.B_Solucion_2 = uibutton(app.GridLayout_3, 'push');
            app.B_Solucion_2.ButtonPushedFcn = createCallbackFcn(app, @B_Solucion_2ButtonPushed, true);
            app.B_Solucion_2.Text = 'SOLUCION';
            app.P_Central = uipanel(app.UIFigure);
            app.P_Central_Tendencias = uipanel(app.P_Central);
            app.P_Central_Tendencias.Visible = 'off';
            app.G_Graficos_Tendencia = uigridlayout(app.P_Central_Tendencias);
            app.P_Tendencias_1 = uipanel(app.G_Graficos_Tendencia);
            app.AnlisisdeDatosLabel = uilabel(app.P_Tendencias_1);
            app.AnlisisdeDatosLabel.Text = 'Análisis de Datos';
            app.P_TendenciaProduccion_1 = uipanel(app.P_Tendencias_1);
            app.G_PvsT_Tendencias_1 = uiaxes(app.P_TendenciaProduccion_1);
            title(app.G_PvsT_Tendencias_1, 'Energía producción vs tiempo')
            xlabel(app.G_PvsT_Tendencias_1, 'X')
            ylabel(app.G_PvsT_Tendencias_1, 'Y')
            zlabel(app.G_PvsT_Tendencias_1, 'Z')
            app.TA_DescripcionAnalisisPvsT_Tendencias = uitextarea(app.P_TendenciaProduccion_1);
            app.P_TendenciaAnalisisRadTemp_1 = uipanel(app.P_Tendencias_1);
            app.G_RadTemp_Tendencias_1 = uiaxes(app.P_TendenciaAnalisisRadTemp_1);
            title(app.G_RadTemp_Tendencias_1, 'LALAL')
            xlabel(app.G_RadTemp_Tendencias_1, 'X')
            ylabel(app.G_RadTemp_Tendencias_1, 'Y')
            zlabel(app.G_RadTemp_Tendencias_1, 'Z')
            app.G_RadTemp_Tendencias_2 = uiaxes(app.P_TendenciaAnalisisRadTemp_1);
            title(app.G_RadTemp_Tendencias_2, 'LALAL')
            xlabel(app.G_RadTemp_Tendencias_2, 'X')
            ylabel(app.G_RadTemp_Tendencias_2, 'Y')
            zlabel(app.G_RadTemp_Tendencias_2, 'Z')
            app.G_RadTemp_Tendencias_2.Visible = 'off';
            app.TA_DescripcionAnalisisRadTemp_Tendencias_1 = uitextarea(app.P_TendenciaAnalisisRadTemp_1);
            app.B_Tendencia_CambiarGraf_1 = uibutton(app.P_TendenciaAnalisisRadTemp_1, 'state');
            app.B_Tendencia_CambiarGraf_1.ValueChangedFcn = createCallbackFcn(app, @B_Tendencia_CambiarGraf_1_1ValueChanged, true);
            app.B_Tendencia_CambiarGraf_1.Text = '';
            app.P_TendenciaCostoMeses_1 = uipanel(app.P_Tendencias_1);
            app.G_CostoMeses_Tendencias_1 = uiaxes(app.P_TendenciaCostoMeses_1);
            title(app.G_CostoMeses_Tendencias_1, 'ANALISIS DEL COSTO KWH ')
            xlabel(app.G_CostoMeses_Tendencias_1, 'X')
            ylabel(app.G_CostoMeses_Tendencias_1, 'Y')
            zlabel(app.G_CostoMeses_Tendencias_1, 'Z')
            app.TA_DescripcionAnalisisCostoMeses_Tendencias_1 = uitextarea(app.P_TendenciaCostoMeses_1);
            app.P_Tendencias_Vinculos = uipanel(app.P_Tendencias_1);
            app.GridLayout = uigridlayout(app.P_Tendencias_Vinculos);
            app.B_AnadirE_Tendencias = uibutton(app.GridLayout, 'push');
            app.B_AnadirE_Tendencias.ButtonPushedFcn = createCallbackFcn(app, @B_Tendencias_AnadirE_View, true);
            app.B_AnadirE_Tendencias.Text = 'Añadir electrodoméstico';
            app.B_AnadirRec_Tendencias = uibutton(app.GridLayout, 'push');
            app.B_AnadirRec_Tendencias.ButtonPushedFcn = createCallbackFcn(app, @B_Tendencias_AnadirRec_View, true);
            app.B_AnadirRec_Tendencias.Text = 'Añadir recibo';
            app.B_EditarRec_Tendencias = uibutton(app.GridLayout, 'push');
            app.B_EditarRec_Tendencias.ButtonPushedFcn = createCallbackFcn(app, @B_Tendencias_ModificarRec_View, true);
            app.B_EditarRec_Tendencias.Text = 'Editar recibos';
            app.B_EditarE_Tendencia_1 = uibutton(app.GridLayout, 'push');
            app.B_EditarE_Tendencia_1.ButtonPushedFcn = createCallbackFcn(app, @B_Tendencias_ModificarE_View, true);
            app.B_EditarE_Tendencia_1.Text = 'Editar electrodoméstico';
            app.P_Central_Electrodomesticos = uipanel(app.P_Central);
            app.P_Central_Electrodomesticos.Visible = 'off';
            app.GridLayout3 = uigridlayout(app.P_Central_Electrodomesticos);
            app.P_Electrodomesticos = uipanel(app.GridLayout3);
            app.P_ModificarElectrodomesticos_IngresarE_1 = uipanel(app.P_Electrodomesticos);
            app.P_ModificarElectrodomesticos_IngresarE_1.Visible = 'off';
            app.Image7 = uiimage(app.P_ModificarElectrodomesticos_IngresarE_1);
            app.ElectrodomsticoLabel = uilabel(app.P_ModificarElectrodomesticos_IngresarE_1);
            app.ElectrodomsticoLabel.Text = 'Electrodoméstico';
            app.B_BuscarElectrodomestico_ModificarE = uibutton(app.P_ModificarElectrodomesticos_IngresarE_1, 'push');
            app.B_BuscarElectrodomestico_ModificarE.Text = '';
            app.Image8 = uiimage(app.P_ModificarElectrodomesticos_IngresarE_1);
            app.Image8.ImageSource = 'Lupa.png';
            app.B_LimpiarCampos_ModificarE = uibutton(app.P_ModificarElectrodomesticos_IngresarE_1, 'push');
            app.B_LimpiarCampos_ModificarE.Enable = 'off';
            app.B_LimpiarCampos_ModificarE.Visible = 'off';
            app.B_LimpiarCampos_ModificarE.Text = 'Limpiar';
            app.PotenciaLabel = uilabel(app.P_ModificarElectrodomesticos_IngresarE_1);
            app.PotenciaLabel.Text = 'Potencia';
            app.Fi_Modificar_ModificarE_1 = uieditfield(app.P_ModificarElectrodomesticos_IngresarE_1, 'numeric');
            app.Fi_Modificar_ModificarE_1.Editable = 'off';
            app.TipoDropDownLabel = uilabel(app.P_ModificarElectrodomesticos_IngresarE_1);
            app.TipoDropDownLabel.Text = 'Tipo';
            app.D_Tipo_ModificarE_1 = uidropdown(app.P_ModificarElectrodomesticos_IngresarE_1);
            app.D_Tipo_ModificarE_1.Items = {'Esencial', 'No Esencial', 'Altamente Esencial'};
            app.D_Tipo_ModificarE_1.Value = 'Esencial';
            app.HorasLabel = uilabel(app.P_ModificarElectrodomesticos_IngresarE_1);
            app.HorasLabel.Text = 'Horas D.';
            app.Fi_Horas_ModificarE_1 = uieditfield(app.P_ModificarElectrodomesticos_IngresarE_1, 'numeric');
            app.Fi_Horas_ModificarE_1.Editable = 'off';
            app.DasLabel = uilabel(app.P_ModificarElectrodomesticos_IngresarE_1);
            app.DasLabel.Text = 'Días';
            app.Fi_Dias_ModificarE_1 = uieditfield(app.P_ModificarElectrodomesticos_IngresarE_1, 'numeric');
            app.Fi_Dias_ModificarE_1.Editable = 'off';
            app.T_Electrodomesticos_Gestionar_1 = uitable(app.P_ModificarElectrodomesticos_IngresarE_1);
            app.T_Electrodomesticos_Gestionar_1.ColumnName = {'codigo'; 'nombre'; 'Potencia'; 'Horas Día'; 'Horas noche'; 'Días'; 'Cantidad'; 'Tipo'};
            app.T_Electrodomesticos_Gestionar_1.RowName = {};
            app.T_Electrodomesticos_Gestionar_1.CellSelectionCallback = createCallbackFcn(app, @MostrarDatos_1Fila, true);
            app.NombreLabel = uilabel(app.P_ModificarElectrodomesticos_IngresarE_1);
            app.NombreLabel.Interpreter = 'tex';
            app.NombreLabel.Text = 'Nombre';
            app.HorasLabel_2 = uilabel(app.P_ModificarElectrodomesticos_IngresarE_1);
            app.HorasLabel_2.Text = 'Horas N.';
            app.Fi_Horas_ModificarE_2 = uieditfield(app.P_ModificarElectrodomesticos_IngresarE_1, 'numeric');
            app.Fi_Horas_ModificarE_2.Editable = 'off';
            app.B_Editar_Electrodomestico = uibutton(app.P_ModificarElectrodomesticos_IngresarE_1, 'push');
            app.B_Editar_Electrodomestico.ButtonPushedFcn = createCallbackFcn(app, @B_Editar_ElectrodomesticoButtonPushed, true);
            app.B_Editar_Electrodomestico.Visible = 'off';
            app.B_Editar_Electrodomestico.Text = 'Editar';
            app.B_Actualizar_Electrodomestico = uibutton(app.P_ModificarElectrodomesticos_IngresarE_1, 'push');
            app.B_Actualizar_Electrodomestico.ButtonPushedFcn = createCallbackFcn(app, @B_Actualizar_ElectrodomesticoButtonPushed, true);
            app.B_Actualizar_Electrodomestico.Visible = 'off';
            app.B_Actualizar_Electrodomestico.Text = 'Actualizar';
            app.B_Eliminar_Electrodomestico = uibutton(app.P_ModificarElectrodomesticos_IngresarE_1, 'push');
            app.B_Eliminar_Electrodomestico.ButtonPushedFcn = createCallbackFcn(app, @B_Eliminar_ElectrodomesticoButtonPushed, true);
            app.B_Eliminar_Electrodomestico.Visible = 'off';
            app.B_Eliminar_Electrodomestico.Text = 'Eliminar';
            app.UEditFieldLabel = uilabel(app.P_ModificarElectrodomesticos_IngresarE_1);
            app.UEditFieldLabel.Text = 'U';
            app.Fi_Unidad_Electrodomestico = uieditfield(app.P_ModificarElectrodomesticos_IngresarE_1, 'numeric');
            app.Fi_Unidad_Electrodomestico.Editable = 'off';
            app.P_Anadir_IngresarE_1 = uipanel(app.P_Electrodomesticos);
            app.P_Anadir_IngresarE_1.Visible = 'off';
            app.B_Agregar_anadir_IngresarE = uibutton(app.P_Anadir_IngresarE_1, 'push');
            app.B_Agregar_anadir_IngresarE.ButtonPushedFcn = createCallbackFcn(app, @B_Agregar_anadir_IngresarE_1ButtonPushed, true);
            app.B_Agregar_anadir_IngresarE.Text = 'Agregar';
            app.TipoDropDown_2Label = uilabel(app.P_Anadir_IngresarE_1);
            app.TipoDropDown_2Label.Text = 'Tipo';
            app.D_Tipo_anadir_IngresarE_1 = uidropdown(app.P_Anadir_IngresarE_1);
            app.D_Tipo_anadir_IngresarE_1.Items = {};
            app.D_Tipo_anadir_IngresarE_1.Placeholder = 'Tipo Esencial';
            app.D_Tipo_anadir_IngresarE_1.Value = {};
            app.NombreEditFieldLabel = uilabel(app.P_Anadir_IngresarE_1);
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.Fi_Nombre_anadir_IngresarE_1 = uieditfield(app.P_Anadir_IngresarE_1, 'text');
            app.Fi_Nombre_anadir_IngresarE_1.Editable = 'off';
            app.Fi_Nombre_anadir_IngresarE_1.Placeholder = 'Electrodomesticos';
            app.PotenciaLabel_2 = uilabel(app.P_Anadir_IngresarE_1);
            app.PotenciaLabel_2.Text = 'Potencia';
            app.Fi_PotenciaEnWatts_IngresarE_1 = uieditfield(app.P_Anadir_IngresarE_1, 'numeric');
            app.UsopordaLabel = uilabel(app.P_Anadir_IngresarE_1);
            app.UsopordaLabel.Text = 'Uso de día';
            app.Fi_HorasDias_IngresarE_1 = uieditfield(app.P_Anadir_IngresarE_1, 'numeric');
            app.WattsLabel = uilabel(app.P_Anadir_IngresarE_1);
            app.WattsLabel.Text = 'Watts';
            app.HorasLabel_3 = uilabel(app.P_Anadir_IngresarE_1);
            app.HorasLabel_3.Text = 'Horas';
            app.D_Electrodomesticos_IngresarE_1 = uilistbox(app.P_Anadir_IngresarE_1);
            app.D_Electrodomesticos_IngresarE_1.Items = {};
            app.D_Electrodomesticos_IngresarE_1.Tooltip = {''};
            app.D_Electrodomesticos_IngresarE_1.ClickedFcn = createCallbackFcn(app, @D_Electrodomesticos_IngresarE_1Clicked, true);
            app.D_Electrodomesticos_IngresarE_1.Value = {};
            app.Fi_Uso_Semana_IngresarE_1 = uieditfield(app.P_Anadir_IngresarE_1, 'numeric');
            app.UsoporsemanaLabel = uilabel(app.P_Anadir_IngresarE_1);
            app.UsoporsemanaLabel.Text = 'Uso por semana';
            app.DiasLabel = uilabel(app.P_Anadir_IngresarE_1);
            app.DiasLabel.Text = 'Días';
            app.UsopordaLabel_2 = uilabel(app.P_Anadir_IngresarE_1);
            app.UsopordaLabel_2.Text = 'Uso de noche';
            app.Fi_HorasNoche_1_IngresarE_1 = uieditfield(app.P_Anadir_IngresarE_1, 'numeric');
            app.HorasLabel_4 = uilabel(app.P_Anadir_IngresarE_1);
            app.HorasLabel_4.Text = 'Horas';
            app.AmbienteDropDownLabel = uilabel(app.P_Anadir_IngresarE_1);
            app.AmbienteDropDownLabel.Text = 'Ambiente';
            app.D_Ambiente_IngresarE_1 = uidropdown(app.P_Anadir_IngresarE_1);
            app.D_Ambiente_IngresarE_1.Items = {};
            app.D_Ambiente_IngresarE_1.ValueChangedFcn = createCallbackFcn(app, @D_Ambiente_IngresarE_1ValueChanged, true);
            app.D_Ambiente_IngresarE_1.Placeholder = 'Seleccione';
            app.D_Ambiente_IngresarE_1.Value = {};
            app.B_Regresar_IngresarE = uibutton(app.P_Anadir_IngresarE_1, 'push');
            app.B_Regresar_IngresarE.ButtonPushedFcn = createCallbackFcn(app, @RegresarAnadirElectrodomesticoView, true);
            app.B_Regresar_IngresarE.Text = 'Regresar';
            app.CantidadEditFieldLabel = uilabel(app.P_Anadir_IngresarE_1);
            app.CantidadEditFieldLabel.Text = 'Cantidad';
            app.Fi_Cantidad_IngresarE_1 = uieditfield(app.P_Anadir_IngresarE_1, 'numeric');
            app.UnidadesLabel = uilabel(app.P_Anadir_IngresarE_1);
            app.UnidadesLabel.Text = 'Ud.';
            app.Image9 = uiimage(app.P_Anadir_IngresarE_1);
            app.P_CalcularConsumo_IngresarE_1 = uipanel(app.P_Electrodomesticos);
            app.P_CalcularConsumo_IngresarE_1.Visible = 'off';
            app.TabGroup = uitabgroup(app.P_CalcularConsumo_IngresarE_1);
            app.ConsumoMensualTab = uitab(app.TabGroup);
            app.ConsumoMensualTab.Title = 'Consumo Mensual';
            app.ConsumoMensualEditFieldLabel = uilabel(app.ConsumoMensualTab);
            app.ConsumoMensualEditFieldLabel.Text = 'Consumo Mensual';
            app.Ln_Consumo_Mensual = uieditfield(app.ConsumoMensualTab, 'numeric');
            app.Ln_Consumo_Mensual.ValueDisplayFormat = '%.2f';
            app.Ln_Consumo_Mensual.Editable = 'off';
            app.ConsumoMensualPromedioEditFieldLabel = uilabel(app.ConsumoMensualTab);
            app.ConsumoMensualPromedioEditFieldLabel.Text = {'Consumo Mensual'; 'Promedio'};
            app.Ln_Consumo_Mensual_Promedio = uieditfield(app.ConsumoMensualTab, 'numeric');
            app.Ln_Consumo_Mensual_Promedio.Editable = 'off';
            app.PotenciaPicoEditFieldLabel = uilabel(app.ConsumoMensualTab);
            app.PotenciaPicoEditFieldLabel.Text = 'Potencia Pico';
            app.Ln_Potencia_Pico = uieditfield(app.ConsumoMensualTab, 'numeric');
            app.Ln_Potencia_Pico.Editable = 'off';
            app.ConsumoRelativoTab = uitab(app.TabGroup);
            app.ConsumoRelativoTab.Title = 'Consumo Relativo';
            app.ConsumoRelativoEditFieldLabel = uilabel(app.ConsumoRelativoTab);
            app.ConsumoRelativoEditFieldLabel.Text = 'Consumo Relativo';
            app.Ln_Consumo_Relativo = uieditfield(app.ConsumoRelativoTab, 'numeric');
            app.Ln_Consumo_Relativo.ValueDisplayFormat = '%.2f';
            app.Ln_Consumo_Relativo.Editable = 'off';
            app.ConsumoRelativoPromedioLabel = uilabel(app.ConsumoRelativoTab);
            app.ConsumoRelativoPromedioLabel.Text = 'Consumo Relativo Promedio';
            app.Ln_Consumo_Relativo_Promedio = uieditfield(app.ConsumoRelativoTab, 'numeric');
            app.Ln_Consumo_Relativo_Promedio.Editable = 'off';
            app.PotenciaPicoRelativaEditFieldLabel = uilabel(app.ConsumoRelativoTab);
            app.PotenciaPicoRelativaEditFieldLabel.Text = 'Potencia Pico Relativa';
            app.Ln_Potencia_Pico_Relativa = uieditfield(app.ConsumoRelativoTab, 'numeric');
            app.Ln_Potencia_Pico_Relativa.Editable = 'off';
            app.Label = uilabel(app.P_CalcularConsumo_IngresarE_1);
            app.Label.Text = {'Se esta tomando en considecación un Panel de 550W a 9 '; 'horas de un funcionamiento del 100%'};
            app.P_inicio_1 = uipanel(app.P_Central);
            app.P_inicio_1.Visible = 'off';
            app.B_Ingresar_Recibos_1 = uibutton(app.P_inicio_1, 'push');
            app.B_Ingresar_Recibos_1.ButtonPushedFcn = createCallbackFcn(app, @IngresarRecibosView_1, true);
            app.B_Ingresar_Recibos_1.Text = 'Ingresar recibos';
            app.B_Ingresar_Electrodomestcos_1 = uibutton(app.P_inicio_1, 'push');
            app.B_Ingresar_Electrodomestcos_1.ButtonPushedFcn = createCallbackFcn(app, @IngresarElectrodomesticosView, true);
            app.B_Ingresar_Electrodomestcos_1.Text = 'Ingresar electrodomésticos';
            app.B_Tendencias_1 = uibutton(app.P_inicio_1, 'push');
            app.B_Tendencias_1.ButtonPushedFcn = createCallbackFcn(app, @B_Tendencias_1_View, true);
            app.B_Tendencias_1.Text = 'Tendencias';
            app.P_Central_Recibos = uipanel(app.P_Central);
            app.P_Central_Recibos.Visible = 'off';
            app.P_IngresarRecibo_1 = uipanel(app.P_Central_Recibos);
            app.P_IngresarRecibo_1.Visible = 'off';
            app.B_Regresar_IngresarRecibo_1 = uibutton(app.P_IngresarRecibo_1, 'push');
            app.B_Regresar_IngresarRecibo_1.ButtonPushedFcn = createCallbackFcn(app, @RegresarReciboElectrodomesticoview, true);
            app.B_Regresar_IngresarRecibo_1.Text = 'Regresar';
            app.B_Ingresar_IngresarRecibo_1 = uibutton(app.P_IngresarRecibo_1, 'push');
            app.B_Ingresar_IngresarRecibo_1.ButtonPushedFcn = createCallbackFcn(app, @B_Ingresar_IngresarRecibo_1_1ButtonPushed, true);
            app.B_Ingresar_IngresarRecibo_1.Text = 'Ingresar';
            app.IngreselosdatosdesureciboLabel = uilabel(app.P_IngresarRecibo_1);
            app.IngreselosdatosdesureciboLabel.Text = 'Ingrese los datos de su recibo';
            app.ConsumoMensualEditFieldLabel_2 = uilabel(app.P_IngresarRecibo_1);
            app.ConsumoMensualEditFieldLabel_2.Text = 'Consumo Mensual';
            app.Fi_ConsumoMensual_IngresarR_1 = uieditfield(app.P_IngresarRecibo_1, 'numeric');
            app.CostoTotalEditFieldLabel = uilabel(app.P_IngresarRecibo_1);
            app.CostoTotalEditFieldLabel.Text = 'Costo Total';
            app.Fi_CostoTotal_IngresarR_1 = uieditfield(app.P_IngresarRecibo_1, 'numeric');
            app.FechaReciboLabel = uilabel(app.P_IngresarRecibo_1);
            app.FechaReciboLabel.Text = 'Fecha Recibo';
            app.Fi_ConsumoMesA_IngresarR_1 = uidatepicker(app.P_IngresarRecibo_1);
            app.Fi_ConsumoMesA_IngresarR_1.DisplayFormat = 'MM.uuuu';
            app.G_Estadistica = uipanel(app.P_Central_Recibos);
            app.G_Estadistica.Visible = 'off';
            app.G_Estadistica_Recibo = uiaxes(app.G_Estadistica);
            title(app.G_Estadistica_Recibo, 'Title')
            xlabel(app.G_Estadistica_Recibo, 'X')
            ylabel(app.G_Estadistica_Recibo, 'Y')
            zlabel(app.G_Estadistica_Recibo, 'Z')
            app.ElectrodomesticosButton = uibutton(app.G_Estadistica, 'push');
            app.ElectrodomesticosButton.Text = 'Electrodomesticos';
            app.RecibosButton = uibutton(app.G_Estadistica, 'push');
            app.RecibosButton.Text = 'Recibos';
            app.A_Year_Estadistica = uidropdown(app.G_Estadistica);
            app.P_TablaRecibo_Consumo_1 = uipanel(app.P_Central_Recibos);
            app.P_TablaRecibo_Consumo_1.Visible = 'off';
            app.T_Recibo_1 = uitable(app.P_TablaRecibo_Consumo_1);
            app.T_Recibo_1.ColumnName = {'Numero Recibo'; 'Consumo Mensual'; 'Costo total'; 'Año'; 'Mes'; 'Estado'};
            app.T_Recibo_1.RowName = {};
            app.T_Recibo_1.SelectionChangedFcn = createCallbackFcn(app, @T_Recibo_1SelectionChanged, true);
            app.B_EditarRecibo_1 = uibutton(app.P_TablaRecibo_Consumo_1, 'push');
            app.B_EditarRecibo_1.ButtonPushedFcn = createCallbackFcn(app, @B_EditarRecibo_1ButtonPushed, true);
            app.B_EditarRecibo_1.Visible = 'off';
            app.B_EditarRecibo_1.Text = 'Editar';
            app.B_EliminarRecibo_1 = uibutton(app.P_TablaRecibo_Consumo_1, 'push');
            app.B_EliminarRecibo_1.ButtonPushedFcn = createCallbackFcn(app, @B_EliminarRecibo_1ButtonPushed, true);
            app.B_EliminarRecibo_1.Visible = 'off';
            app.B_EliminarRecibo_1.Text = 'Eliminar';
            app.B_ActualizarRecibo_1 = uibutton(app.P_TablaRecibo_Consumo_1, 'push');
            app.B_ActualizarRecibo_1.ButtonPushedFcn = createCallbackFcn(app, @B_ActualizarRecibo_1ButtonPushed, true);
            app.B_ActualizarRecibo_1.Visible = 'off';
            app.B_ActualizarRecibo_1.Text = 'Actualizar';
            app.L_Recibo = uilabel(app.P_TablaRecibo_Consumo_1);
            app.L_Recibo.Text = 'Recibo';
            app.I_Recibo = uiimage(app.P_TablaRecibo_Consumo_1);
            app.I_Recibo.ImageClickedFcn = createCallbackFcn(app, @I_ReciboImageClicked, true);
            app.I_Recibo.ImageSource = 'Estadistica.png';
            app.ConsumomensualEditFieldLabel = uilabel(app.P_TablaRecibo_Consumo_1);
            app.ConsumomensualEditFieldLabel.Text = 'Consumo mensual';
            app.Fi_ReciboConsumo_1 = uieditfield(app.P_TablaRecibo_Consumo_1, 'numeric');
            app.Fi_ReciboConsumo_1.Editable = 'off';
            app.CostoTotalEditField_2Label = uilabel(app.P_TablaRecibo_Consumo_1);
            app.CostoTotalEditField_2Label.Text = 'Costo Total';
            app.Fi_ReciboCostoTotal_1 = uieditfield(app.P_TablaRecibo_Consumo_1, 'numeric');
            app.Fi_ReciboCostoTotal_1.Editable = 'off';
            app.FechaReciboDatePickerLabel = uilabel(app.P_TablaRecibo_Consumo_1);
            app.FechaReciboDatePickerLabel.Text = 'Fecha-Recibo';
            app.D_Fecha_Recibo = uidatepicker(app.P_TablaRecibo_Consumo_1);
            app.D_Fecha_Recibo.DisplayFormat = 'MM.uuuu';
            app.P_inicio_Instalacion = uipanel(app.P_Central);
            app.P_inicio_Instalacion.Visible = 'off';
            app.B_System_Hibrido = uibutton(app.P_inicio_Instalacion, 'push');
            app.B_System_Hibrido.ButtonPushedFcn = createCallbackFcn(app, @B_System_HibridoButtonPushed, true);
            app.B_System_Hibrido.Text = 'Sistema Hibrido';
            app.B_System_YY = uibutton(app.P_inicio_Instalacion, 'push');
            app.B_System_YY.ButtonPushedFcn = createCallbackFcn(app, @B_System_YYButtonPushed, true);
            app.B_System_YY.Text = 'Sistema YYY';
            app.P_Central_Solucion = uipanel(app.P_Central);
            app.P_Central_Solucion.Visible = 'off';
            app.P_Solucion = uipanel(app.P_Central_Solucion);
            app.P_Solucion.Visible = 'off';
            app.Panel_4 = uipanel(app.P_Solucion);
            app.PANELLabel_3 = uilabel(app.Panel_4);
            app.PANELLabel_3.Text = 'PANEL';
            app.MODELOEditField_3Label = uilabel(app.Panel_4);
            app.MODELOEditField_3Label.Text = 'MODELO';
            app.MODELOEditField_3 = uieditfield(app.Panel_4, 'text');
            app.MODELOEditField_3.Editable = 'off';
            app.NumPanel = uieditfield(app.Panel_4, 'numeric');
            app.NumPanel.ValueDisplayFormat = '%.0f';
            app.Panel_5 = uipanel(app.P_Solucion);
            app.BATERIALabel = uilabel(app.Panel_5);
            app.BATERIALabel.Text = 'BATERIA';
            app.MODELOEditField_4Label = uilabel(app.Panel_5);
            app.MODELOEditField_4Label.Text = 'MODELO';
            app.MODELOEditField_4 = uieditfield(app.Panel_5, 'text');
            app.MODELOEditField_4.Editable = 'off';
            app.NumBateria = uieditfield(app.Panel_5, 'numeric');
            app.NumBateria.ValueDisplayFormat = '%.0f';
            app.Panel_6 = uipanel(app.P_Solucion);
            app.INVERSORLabel_3 = uilabel(app.Panel_6);
            app.INVERSORLabel_3.Text = 'INVERSOR';
            app.MODELOEditField_5Label = uilabel(app.Panel_6);
            app.MODELOEditField_5Label.Text = 'MODELO';
            app.MODELOEditField_5 = uieditfield(app.Panel_6, 'text');
            app.MODELOEditField_5.Editable = 'off';
            app.NumInversor = uieditfield(app.Panel_6, 'numeric');
            app.NumInversor.ValueDisplayFormat = '%.0f';
            app.COSTOINSTALACIONEditFieldLabel_2 = uilabel(app.P_Solucion);
            app.COSTOINSTALACIONEditFieldLabel_2.Text = 'COSTO INSTALACION';
            app.Fi_Costo_Instalacion_Solucion_2 = uieditfield(app.P_Solucion, 'numeric');
            app.Fi_Costo_Instalacion_Solucion_2.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Instalacion_Solucion_2.AllowEmpty = 'on';
            app.Fi_Costo_Instalacion_Solucion_2.Editable = 'off';
            app.COSTOCOMPRAEditFieldLabel_2 = uilabel(app.P_Solucion);
            app.COSTOCOMPRAEditFieldLabel_2.Text = 'COSTO COMPRA';
            app.Fi_Costo_Compra_Solucion_2 = uieditfield(app.P_Solucion, 'numeric');
            app.Fi_Costo_Compra_Solucion_2.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Compra_Solucion_2.AllowEmpty = 'on';
            app.Fi_Costo_Compra_Solucion_2.Editable = 'off';
            app.COSTOMANTENIMIENTOLabel_2 = uilabel(app.P_Solucion);
            app.COSTOMANTENIMIENTOLabel_2.Text = {'COSTO'; 'MANTENIMIENTO'};
            app.Fi_Costo_Mantenimiento_Solucion_2 = uieditfield(app.P_Solucion, 'numeric');
            app.Fi_Costo_Mantenimiento_Solucion_2.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Mantenimiento_Solucion_2.AllowEmpty = 'on';
            app.Fi_Costo_Mantenimiento_Solucion_2.Editable = 'off';
            app.COSTOTOTALLabel_2 = uilabel(app.P_Solucion);
            app.COSTOTOTALLabel_2.Text = 'COSTO TOTAL';
            app.Fi_Costo_Total_Solucion_2 = uieditfield(app.P_Solucion, 'numeric');
            app.Fi_Costo_Total_Solucion_2.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Total_Solucion_2.AllowEmpty = 'on';
            app.Fi_Costo_Total_Solucion_2.Editable = 'off';
            app.I_Imagen_Solucion_2 = uiimage(app.P_Solucion);
            app.P_Mostrar = uipanel(app.P_Central_Solucion);
            app.P_Mostrar.Visible = 'off';
            app.P_Panel_3 = uipanel(app.P_Mostrar);
            app.I_Imagen_Panel_2 = uiimage(app.P_Panel_3);
            app.PANELLabel_4 = uilabel(app.P_Panel_3);
            app.PANELLabel_4.Text = 'PANEL';
            app.MODELOEditFieldLabel_4 = uilabel(app.P_Panel_3);
            app.MODELOEditFieldLabel_4.Text = 'MODELO';
            app.Fi_Modelo_Panel_2 = uieditfield(app.P_Panel_3, 'text');
            app.Fi_Modelo_Panel_2.Editable = 'off';
            app.POTENCIAEditFieldLabel_3 = uilabel(app.P_Panel_3);
            app.POTENCIAEditFieldLabel_3.Text = 'POTENCIA';
            app.Fi_Potencia_Panel_2 = uieditfield(app.P_Panel_3, 'numeric');
            app.Fi_Potencia_Panel_2.Editable = 'off';
            app.ANCHOLabel_3 = uilabel(app.P_Panel_3);
            app.ANCHOLabel_3.Text = 'ANCHO';
            app.Fi_Ancho_Panel_2 = uieditfield(app.P_Panel_3, 'numeric');
            app.Fi_Ancho_Panel_2.Editable = 'off';
            app.COSTOLabel_2 = uilabel(app.P_Panel_3);
            app.COSTOLabel_2.Text = 'COSTO';
            app.Fi_Costo_Panel_2 = uieditfield(app.P_Panel_3, 'numeric');
            app.Fi_Costo_Panel_2.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Panel_2.Editable = 'off';
            app.VOLTAJELabel_3 = uilabel(app.P_Panel_3);
            app.VOLTAJELabel_3.Text = 'VOLTAJE';
            app.Fi_Voltaje_Panel_2 = uieditfield(app.P_Panel_3, 'numeric');
            app.Fi_Voltaje_Panel_2.Editable = 'off';
            app.ALTOLabel_2 = uilabel(app.P_Panel_3);
            app.ALTOLabel_2.Text = 'ALTO';
            app.Fi_Alto_Panel_2 = uieditfield(app.P_Panel_3, 'numeric');
            app.Fi_Alto_Panel_2.Editable = 'off';
            app.TIEMPODELabel_3 = uilabel(app.P_Panel_3);
            app.TIEMPODELabel_3.Text = {'TIEMPO DE '; 'VIDA'};
            app.Fi_Tiempo_Vida_Panel_2 = uieditfield(app.P_Panel_3, 'numeric');
            app.Fi_Tiempo_Vida_Panel_2.Editable = 'off';
            app.P_Bateria = uipanel(app.P_Mostrar);
            app.I_Imagen_Bateria = uiimage(app.P_Bateria);
            app.BATERIALabel_2 = uilabel(app.P_Bateria);
            app.BATERIALabel_2.Text = 'BATERIA';
            app.MODELOEditFieldLabel_5 = uilabel(app.P_Bateria);
            app.MODELOEditFieldLabel_5.Text = 'MODELO';
            app.Fi_Modelo_Bateria = uieditfield(app.P_Bateria, 'text');
            app.Fi_Modelo_Bateria.Editable = 'off';
            app.CAPACIDADLabel = uilabel(app.P_Bateria);
            app.CAPACIDADLabel.Text = 'CAPACIDAD';
            app.Fi_Capacidad_Bateria = uieditfield(app.P_Bateria, 'numeric');
            app.Fi_Capacidad_Bateria.Editable = 'off';
            app.ANCHOLabel_4 = uilabel(app.P_Bateria);
            app.ANCHOLabel_4.Text = 'ANCHO';
            app.Fi_Ancho_Bateria = uieditfield(app.P_Bateria, 'numeric');
            app.Fi_Ancho_Bateria.Editable = 'off';
            app.COSTOLabel_3 = uilabel(app.P_Bateria);
            app.COSTOLabel_3.Text = 'COSTO';
            app.Fi_Costo_Bateria = uieditfield(app.P_Bateria, 'numeric');
            app.Fi_Costo_Bateria.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Bateria.Editable = 'off';
            app.VOLTAJELabel_4 = uilabel(app.P_Bateria);
            app.VOLTAJELabel_4.Text = 'LARGO';
            app.Fi_Largo_Bateria = uieditfield(app.P_Bateria, 'numeric');
            app.Fi_Largo_Bateria.Editable = 'off';
            app.ALTOLabel_3 = uilabel(app.P_Bateria);
            app.ALTOLabel_3.Text = 'ALTO';
            app.Fi_Alto_Bateria = uieditfield(app.P_Bateria, 'numeric');
            app.Fi_Alto_Bateria.Editable = 'off';
            app.TIEMPODELabel_4 = uilabel(app.P_Bateria);
            app.TIEMPODELabel_4.Text = 'VOLTAJE';
            app.Fi_Voltaje_Bateria = uieditfield(app.P_Bateria, 'numeric');
            app.Fi_Voltaje_Bateria.Editable = 'off';
            app.P_Panel_4 = uipanel(app.P_Mostrar);
            app.I_Imagen_Inversor_2 = uiimage(app.P_Panel_4);
            app.INVERSORLabel_4 = uilabel(app.P_Panel_4);
            app.INVERSORLabel_4.Text = 'INVERSOR';
            app.MODELOEditFieldLabel_6 = uilabel(app.P_Panel_4);
            app.MODELOEditFieldLabel_6.Text = 'MODELO';
            app.Fi_Modelo_Inversor_2 = uieditfield(app.P_Panel_4, 'text');
            app.POTENCIAEditFieldLabel_4 = uilabel(app.P_Panel_4);
            app.POTENCIAEditFieldLabel_4.Text = 'POTENCIA';
            app.Fi_Potencia_Inversor_2 = uieditfield(app.P_Panel_4, 'numeric');
            app.Fi_Potencia_Inversor_2.Editable = 'off';
            app.ANCHOLabel_5 = uilabel(app.P_Panel_4);
            app.ANCHOLabel_5.Text = 'V. ADMISIBLE';
            app.Fi_VAdmisible_Inversor_2 = uieditfield(app.P_Panel_4, 'numeric');
            app.Fi_VAdmisible_Inversor_2.Editable = 'off';
            app.VOLTAJELabel_5 = uilabel(app.P_Panel_4);
            app.VOLTAJELabel_5.Text = 'COSTO';
            app.Fi_Costo_Inversor_2 = uieditfield(app.P_Panel_4, 'numeric');
            app.Fi_Costo_Inversor_2.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Inversor_2.Editable = 'off';
            app.TIEMPODELabel_5 = uilabel(app.P_Panel_4);
            app.TIEMPODELabel_5.Text = {'TIEMPO DE '; 'VIDA'};
            app.Fi_Tiempo_Vida_Inversor_2 = uieditfield(app.P_Panel_4, 'numeric');
            app.Fi_Tiempo_Vida_Inversor_2.Editable = 'off';
            app.P_Central_Solucion_Hibrido = uipanel(app.P_Central);
            app.P_Central_Solucion_Hibrido.Visible = 'off';
            app.P_Solucion_Hibrido = uipanel(app.P_Central_Solucion_Hibrido);
            app.P_Solucion_Hibrido.Visible = 'off';
            app.I_Imagen_Solucion = uiimage(app.P_Solucion_Hibrido);
            app.Panel_2 = uipanel(app.P_Solucion_Hibrido);
            app.PANELLabel = uilabel(app.Panel_2);
            app.PANELLabel.Text = 'PANEL';
            app.MODELOEditFieldLabel = uilabel(app.Panel_2);
            app.MODELOEditFieldLabel.Text = 'MODELO';
            app.MODELOEditField = uieditfield(app.Panel_2, 'text');
            app.MODELOEditField.Editable = 'off';
            app.NumPanelH = uieditfield(app.Panel_2, 'numeric');
            app.NumPanelH.ValueDisplayFormat = '%.0f';
            app.Panel_3 = uipanel(app.P_Solucion_Hibrido);
            app.INVERSORLabel = uilabel(app.Panel_3);
            app.INVERSORLabel.Text = 'INVERSOR';
            app.MODELOEditField_2Label = uilabel(app.Panel_3);
            app.MODELOEditField_2Label.Text = 'MODELO';
            app.MODELOEditField_2 = uieditfield(app.Panel_3, 'text');
            app.MODELOEditField_2.Editable = 'off';
            app.NumInversorH = uieditfield(app.Panel_3, 'numeric');
            app.NumInversorH.ValueDisplayFormat = '%.0f';
            app.COSTOINSTALACIONEditFieldLabel = uilabel(app.P_Solucion_Hibrido);
            app.COSTOINSTALACIONEditFieldLabel.Text = 'COSTO INSTALACION';
            app.Fi_Costo_Instalacion_Solucion = uieditfield(app.P_Solucion_Hibrido, 'numeric');
            app.Fi_Costo_Instalacion_Solucion.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Instalacion_Solucion.AllowEmpty = 'on';
            app.Fi_Costo_Instalacion_Solucion.Editable = 'off';
            app.COSTOCOMPRAEditFieldLabel = uilabel(app.P_Solucion_Hibrido);
            app.COSTOCOMPRAEditFieldLabel.Text = 'COSTO COMPRA';
            app.Fi_Costo_Compra_Solucion = uieditfield(app.P_Solucion_Hibrido, 'numeric');
            app.Fi_Costo_Compra_Solucion.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Compra_Solucion.AllowEmpty = 'on';
            app.Fi_Costo_Compra_Solucion.Editable = 'off';
            app.COSTOMANTENIMIENTOLabel = uilabel(app.P_Solucion_Hibrido);
            app.COSTOMANTENIMIENTOLabel.Text = {'COSTO'; 'MANTENIMIENTO'};
            app.Fi_Costo_Mantenimiento_Solucion = uieditfield(app.P_Solucion_Hibrido, 'numeric');
            app.Fi_Costo_Mantenimiento_Solucion.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Mantenimiento_Solucion.AllowEmpty = 'on';
            app.Fi_Costo_Mantenimiento_Solucion.Editable = 'off';
            app.COSTOTOTALLabel = uilabel(app.P_Solucion_Hibrido);
            app.COSTOTOTALLabel.Text = 'COSTO TOTAL';
            app.Fi_Costo_Total_Solucion = uieditfield(app.P_Solucion_Hibrido, 'numeric');
            app.Fi_Costo_Total_Solucion.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Total_Solucion.AllowEmpty = 'on';
            app.Fi_Costo_Total_Solucion.Editable = 'off';
            app.SistemaHbridoLabel = uilabel(app.P_Solucion_Hibrido);
            app.SistemaHbridoLabel.Text = 'Sistema Híbrido';
            app.P_Mostrar_Hibrido = uipanel(app.P_Central_Solucion_Hibrido);
            app.P_Mostrar_Hibrido.Visible = 'off';
            app.P_Panel = uipanel(app.P_Mostrar_Hibrido);
            app.I_Imagen_Panel = uiimage(app.P_Panel);
            app.PANELLabel_2 = uilabel(app.P_Panel);
            app.PANELLabel_2.Text = 'PANEL';
            app.MODELOEditFieldLabel_2 = uilabel(app.P_Panel);
            app.MODELOEditFieldLabel_2.Text = 'MODELO';
            app.Fi_Modelo_Panel = uieditfield(app.P_Panel, 'text');
            app.Fi_Modelo_Panel.Editable = 'off';
            app.POTENCIAEditFieldLabel = uilabel(app.P_Panel);
            app.POTENCIAEditFieldLabel.Text = 'POTENCIA';
            app.Fi_Potencia_Panel = uieditfield(app.P_Panel, 'numeric');
            app.Fi_Potencia_Panel.Editable = 'off';
            app.ANCHOLabel = uilabel(app.P_Panel);
            app.ANCHOLabel.Text = 'ANCHO';
            app.Fi_Ancho_Panel = uieditfield(app.P_Panel, 'numeric');
            app.Fi_Ancho_Panel.Editable = 'off';
            app.COSTOLabel = uilabel(app.P_Panel);
            app.COSTOLabel.Text = 'COSTO';
            app.Fi_Costo_Panel = uieditfield(app.P_Panel, 'numeric');
            app.Fi_Costo_Panel.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Panel.Editable = 'off';
            app.VOLTAJELabel = uilabel(app.P_Panel);
            app.VOLTAJELabel.Text = 'VOLTAJE';
            app.Fi_Voltaje_Panel = uieditfield(app.P_Panel, 'numeric');
            app.Fi_Voltaje_Panel.Editable = 'off';
            app.ALTOLabel = uilabel(app.P_Panel);
            app.ALTOLabel.Text = 'ALTO';
            app.Fi_Alto_Panel = uieditfield(app.P_Panel, 'numeric');
            app.Fi_Alto_Panel.Editable = 'off';
            app.TIEMPODELabel = uilabel(app.P_Panel);
            app.TIEMPODELabel.Text = {'TIEMPO DE '; 'VIDA'};
            app.Fi_Tiempo_Vida_Panel = uieditfield(app.P_Panel, 'numeric');
            app.Fi_Tiempo_Vida_Panel.Editable = 'off';
            app.P_Panel_2 = uipanel(app.P_Mostrar_Hibrido);
            app.I_Imagen_Inversor = uiimage(app.P_Panel_2);
            app.INVERSORLabel_2 = uilabel(app.P_Panel_2);
            app.INVERSORLabel_2.Text = 'INVERSOR';
            app.MODELOEditFieldLabel_3 = uilabel(app.P_Panel_2);
            app.MODELOEditFieldLabel_3.Text = 'MODELO';
            app.Fi_Modelo_Inversor = uieditfield(app.P_Panel_2, 'text');
            app.POTENCIAEditFieldLabel_2 = uilabel(app.P_Panel_2);
            app.POTENCIAEditFieldLabel_2.Text = 'POTENCIA';
            app.Fi_Potencia_Inversor = uieditfield(app.P_Panel_2, 'numeric');
            app.Fi_Potencia_Inversor.Editable = 'off';
            app.ANCHOLabel_2 = uilabel(app.P_Panel_2);
            app.ANCHOLabel_2.Text = 'V. ADMISIBLE';
            app.Fi_VAdmisible_Inversor = uieditfield(app.P_Panel_2, 'numeric');
            app.Fi_VAdmisible_Inversor.Editable = 'off';
            app.VOLTAJELabel_2 = uilabel(app.P_Panel_2);
            app.VOLTAJELabel_2.Text = 'COSTO';
            app.Fi_Costo_Inversor = uieditfield(app.P_Panel_2, 'numeric');
            app.Fi_Costo_Inversor.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Inversor.Editable = 'off';
            app.TIEMPODELabel_2 = uilabel(app.P_Panel_2);
            app.TIEMPODELabel_2.Text = {'TIEMPO DE '; 'VIDA'};
            app.Fi_Tiempo_Vida_Inversor = uieditfield(app.P_Panel_2, 'numeric');
            app.Fi_Tiempo_Vida_Inversor.Editable = 'off';
            app.SistemaHbridoLabel_2 = uilabel(app.P_Mostrar_Hibrido);
            app.SistemaHbridoLabel_2.Text = 'Sistema Híbrido';
            app.Panel = uipanel(app.UIFigure);
            app.Container_Botones = uigridlayout(app.Panel);
            app.B_CalculadoraConsumo_1 = uibutton(app.Container_Botones, 'push');
            app.B_CalculadoraConsumo_1.ButtonPushedFcn = createCallbackFcn(app, @CalculadoraConsumoView_1, true);
            app.B_CalculadoraConsumo_1.Text = 'Calculadora de Consumo';
            app.B_Instalacion = uibutton(app.Container_Botones, 'push');
            app.B_Instalacion.ButtonPushedFcn = createCallbackFcn(app, @B_InstalacionButtonPushed, true);
            app.B_Instalacion.Text = 'Instalación';
            app.B_EnergiaProducida_3 = uibutton(app.Container_Botones, 'push');
            app.B_EnergiaProducida_3.Text = 'Energía Producida';
            app.CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel = uilabel(app.UIFigure);
            app.CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel.Text = 'Calculadora de ROI en la implementación de energía solar vs eléctrica';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Principal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end