classdef M2_Mockup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        P_inicio_Instalacion            matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        B_System_Hibrido                matlab.ui.control.Button
        B_System_YY                     matlab.ui.control.Button
        P_Solucion_Hibrido              matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        Fi_Costo_Total_Solucion_2       matlab.ui.control.NumericEditField
        COSTOTOTALLabel_2               matlab.ui.control.Label
        Fi_Costo_Mantenimiento_Solucion_2  matlab.ui.control.NumericEditField
        COSTOMANTENIMIENTOLabel_2       matlab.ui.control.Label
        Fi_Costo_Compra_Solucion_2      matlab.ui.control.NumericEditField
        COSTOCOMPRAEditFieldLabel_2     matlab.ui.control.Label
        Fi_Costo_Instalacion_Solucion_2  matlab.ui.control.NumericEditField
        COSTOINSTALACIONEditFieldLabel_2  matlab.ui.control.Label
        SistemaHbridoLabel_2            matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        MODELOEditField_6               matlab.ui.control.EditField
        MODELOEditField_6Label          matlab.ui.control.Label
        NumInversorH                    matlab.ui.control.NumericEditField
        INVERSORLabel_5                 matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        MODELOEditField_4               matlab.ui.control.EditField
        MODELOEditField_4Label          matlab.ui.control.Label
        NumPanelH                       matlab.ui.control.NumericEditField
        PANELLabel_4                    matlab.ui.control.Label
        I_Imagen_Solucion_2             matlab.ui.control.Image
        P_Mostrar_Hibrido               matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        SistemaHbridoLabel              matlab.ui.control.Label
        P_Panel_5                       matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        Fi_Tiempo_Vida_Inversor_2       matlab.ui.control.NumericEditField
        TIEMPODELabel_6                 matlab.ui.control.Label
        Fi_Costo_Inversor_2             matlab.ui.control.NumericEditField
        VOLTAJELabel_6                  matlab.ui.control.Label
        Fi_VAdmisible_Inversor_2        matlab.ui.control.NumericEditField
        ANCHOLabel_6                    matlab.ui.control.Label
        Fi_Potencia_Inversor_2          matlab.ui.control.NumericEditField
        POTENCIAEditFieldLabel_5        matlab.ui.control.Label
        Fi_Modelo_Inversor_2            matlab.ui.control.EditField
        MODELOEditFieldLabel_7          matlab.ui.control.Label
        INVERSORLabel_4                 matlab.ui.control.Label
        I_Imagen_Inversor_2             matlab.ui.control.Image
        P_Panel_4                       matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        Fi_Tiempo_Vida_Panel_2          matlab.ui.control.NumericEditField
        TIEMPODELabel_4                 matlab.ui.control.Label
        Fi_Alto_Panel_2                 matlab.ui.control.NumericEditField
        ALTOLabel_3                     matlab.ui.control.Label
        Fi_Voltaje_Panel_2              matlab.ui.control.NumericEditField
        VOLTAJELabel_4                  matlab.ui.control.Label
        Fi_Costo_Panel_2                matlab.ui.control.NumericEditField
        COSTOLabel_3                    matlab.ui.control.Label
        Fi_Ancho_Panel_2                matlab.ui.control.NumericEditField
        ANCHOLabel_4                    matlab.ui.control.Label
        Fi_Potencia_Panel_2             matlab.ui.control.NumericEditField
        POTENCIAEditFieldLabel_4        matlab.ui.control.Label
        Fi_Modelo_Panel_2               matlab.ui.control.EditField
        MODELOEditFieldLabel_5          matlab.ui.control.Label
        PANELLabel_3                    matlab.ui.control.Label
        I_Imagen_Panel_2                matlab.ui.control.Image
        P_Solucion                      matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        Fi_Costo_Total_Solucion         matlab.ui.control.NumericEditField
        COSTOTOTALLabel                 matlab.ui.control.Label
        Fi_Costo_Mantenimiento_Solucion  matlab.ui.control.NumericEditField
        COSTOMANTENIMIENTOLabel         matlab.ui.control.Label
        Fi_Costo_Compra_Solucion        matlab.ui.control.NumericEditField
        COSTOCOMPRAEditFieldLabel       matlab.ui.control.Label
        Fi_Costo_Instalacion_Solucion   matlab.ui.control.NumericEditField
        COSTOINSTALACIONEditFieldLabel  matlab.ui.control.Label
        I_Imagen_Solucion               matlab.ui.control.Image
        Panel_3                         matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        MODELOEditField_3               matlab.ui.control.EditField
        MODELOEditField_3Label_2        matlab.ui.control.Label
        NumInversor                     matlab.ui.control.NumericEditField
        INVERSORLabel_2                 matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        MODELOEditField_2               matlab.ui.control.EditField
        MODELOEditField_2Label_2        matlab.ui.control.Label
        NumBateria                      matlab.ui.control.NumericEditField
        BATERIALabel_2                  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        MODELOEditField                 matlab.ui.control.EditField
        MODELOEditFieldLabel_2          matlab.ui.control.Label
        NumPanel                        matlab.ui.control.NumericEditField
        PANELLabel_2                    matlab.ui.control.Label
        P_Mostrar                       matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        P_Panel_3                       matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        Fi_Tiempo_Vida_Inversor         matlab.ui.control.NumericEditField
        TIEMPODELabel_3                 matlab.ui.control.Label
        Fi_Costo_Inversor               matlab.ui.control.NumericEditField
        VOLTAJELabel_3                  matlab.ui.control.Label
        Fi_VAdmisible_Inversor          matlab.ui.control.NumericEditField
        ANCHOLabel_3                    matlab.ui.control.Label
        Fi_Potencia_Inversor            matlab.ui.control.NumericEditField
        POTENCIAEditFieldLabel_3        matlab.ui.control.Label
        Fi_Modelo_Inversor              matlab.ui.control.EditField
        MODELOEditFieldLabel_4          matlab.ui.control.Label
        INVERSORLabel_3                 matlab.ui.control.Label
        I_Imagen_Inversor               matlab.ui.control.Image
        P_Bateria_2                     matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        Fi_Voltaje_Bateria              matlab.ui.control.NumericEditField
        TIEMPODELabel_2                 matlab.ui.control.Label
        Fi_Alto_Bateria                 matlab.ui.control.NumericEditField
        ALTOLabel_2                     matlab.ui.control.Label
        Fi_Largo_Bateria                matlab.ui.control.NumericEditField
        VOLTAJELabel_2                  matlab.ui.control.Label
        Fi_Costo_Bateria                matlab.ui.control.NumericEditField
        COSTOLabel_2                    matlab.ui.control.Label
        Fi_Ancho_Bateria                matlab.ui.control.NumericEditField
        ANCHOLabel_2                    matlab.ui.control.Label
        Fi_Capacidad_Bateria            matlab.ui.control.NumericEditField
        CAPACIDADLabel                  matlab.ui.control.Label
        Fi_Modelo_Bateria               matlab.ui.control.EditField
        MODELOEditFieldLabel_3          matlab.ui.control.Label
        BATERIALabel_3                  matlab.ui.control.Label
        I_Imagen_Bateria                matlab.ui.control.Image
        P_Panel_1                       matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        Fi_Tiempo_Vida_Panel            matlab.ui.control.NumericEditField
        TIEMPODELabel                   matlab.ui.control.Label
        Fi_Alto_Panel                   matlab.ui.control.NumericEditField
        ALTOLabel                       matlab.ui.control.Label
        Fi_Voltaje_Panel                matlab.ui.control.NumericEditField
        VOLTAJELabel                    matlab.ui.control.Label
        Fi_Costo_Panel                  matlab.ui.control.NumericEditField
        COSTOLabel                      matlab.ui.control.Label
        Fi_Ancho_Panel                  matlab.ui.control.NumericEditField
        ANCHOLabel                      matlab.ui.control.Label
        Fi_Potencia_Panel               matlab.ui.control.NumericEditField
        POTENCIAEditFieldLabel          matlab.ui.control.Label
        Fi_Modelo_Panel                 matlab.ui.control.EditField
        MODELOEditFieldLabel            matlab.ui.control.Label
        PANELLabel                      matlab.ui.control.Label
        I_Imagen_Panel                  matlab.ui.control.Image
        P_Botones_Instalacion_Hibrido   matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        B_Mostrar_2                     matlab.ui.control.Button
        B_Solucion_2                    matlab.ui.control.Button
        P_Botones_Instalacion           matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        B_Mostrar                       matlab.ui.control.Button
        B_Solucion                      matlab.ui.control.Button
        CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel  matlab.ui.control.Label
        P_Botones_Inicio                matlab.ui.container.Panel
        Container_Botones               matlab.ui.container.GridLayout
        B_EnergiaProducida              matlab.ui.control.Button
        B_Instalacion                   matlab.ui.control.Button
        B_CalculadoraConsumo            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: B_Mostrar
        function B_MostrarButtonPushed(app, event)
            app.P_Solucion.Visible ="off";
            app.P_Mostrar.Visible = "on";
        end

        % Button pushed function: B_Solucion
        function B_SolucionButtonPushed(app, event)
            app.P_Solucion.Visible ="on";
            app.P_Mostrar.Visible = "off";
        end

        % Button pushed function: B_Instalacion
        function B_InstalacionButtonPushed(app, event)
            app.P_inicio_Instalacion.Visible = "on";
            app.P_Botones_Instalacion.Visible = "off";
            app.P_Botones_Instalacion_Hibrido.Visible = "off";
            app.P_Solucion_Hibrido.Visible = "off";
            app.P_Mostrar_Hibrido.Visible = "off";
            
        
        end

        % Button pushed function: B_System_YY
        function B_System_YYButtonPushed(app, event)
            app.P_inicio_Instalacion.Visible = "off";
            app.P_Botones_Instalacion.Visible = "on";
            B_SolucionButtonPushed(app, event);
         
            M2_MostrarCostos(app);

            %Para boton Mostrar
            %PANEL
            M2_MostrarModeloPanel(app);
            
            %BATERIA
            M2_MostrarModeloBateria(app);

            %INVERSOR
            M2_MostrarModeloInversor(app);
            
        end

        % Button pushed function: B_System_Hibrido
        function B_System_HibridoButtonPushed(app, event)
            app.P_inicio_Instalacion.Visible = "off";
            app.P_Botones_Instalacion_Hibrido.Visible = "on";

            B_Solucion_2ButtonPushed(app, event);
            
            M2_MostrarCostos(app);
            
            %PANEL
            M2_MostrarModeloPanel(app);

            %BATERIA
            M2_MostrarModeloBateria(app);

        end

        % Button pushed function: B_Solucion_2
        function B_Solucion_2ButtonPushed(app, event)
            app.P_Solucion_Hibrido.Visible = "on";
            app.P_Mostrar_Hibrido.Visible = "off";
        end

        % Button pushed function: B_Mostrar_2
        function B_Mostrar_2ButtonPushed(app, event)
            app.P_Solucion_Hibrido.Visible = "off";
            app.P_Mostrar_Hibrido.Visible = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.P_Botones_Inicio = uipanel(app.GridLayout);
            app.Container_Botones = uigridlayout(app.P_Botones_Inicio);
            app.B_CalculadoraConsumo = uibutton(app.Container_Botones, 'push');
            app.B_CalculadoraConsumo.Text = 'Calculadora de Consumo';
            app.B_Instalacion = uibutton(app.Container_Botones, 'push');
            app.B_Instalacion.ButtonPushedFcn = createCallbackFcn(app, @B_InstalacionButtonPushed, true);
            app.B_Instalacion.Text = 'Instalación';
            app.B_EnergiaProducida = uibutton(app.Container_Botones, 'push');
            app.B_EnergiaProducida.Text = 'Energía Producida';
            app.CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel = uilabel(app.GridLayout);
            app.CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel.Text = 'Calculadora de ROI en la implementación de energía solar vs eléctrica';
            app.P_Botones_Instalacion = uipanel(app.GridLayout);
            app.P_Botones_Instalacion.Visible = 'off';
            app.GridLayout18 = uigridlayout(app.P_Botones_Instalacion);
            app.B_Solucion = uibutton(app.GridLayout18, 'push');
            app.B_Solucion.ButtonPushedFcn = createCallbackFcn(app, @B_SolucionButtonPushed, true);
            app.B_Solucion.Text = 'SOLUCION';
            app.B_Mostrar = uibutton(app.GridLayout18, 'push');
            app.B_Mostrar.ButtonPushedFcn = createCallbackFcn(app, @B_MostrarButtonPushed, true);
            app.B_Mostrar.Text = 'MOSTRAR';
            app.P_Botones_Instalacion_Hibrido = uipanel(app.GridLayout);
            app.P_Botones_Instalacion_Hibrido.Visible = 'off';
            app.GridLayout17 = uigridlayout(app.P_Botones_Instalacion_Hibrido);
            app.B_Solucion_2 = uibutton(app.GridLayout17, 'push');
            app.B_Solucion_2.ButtonPushedFcn = createCallbackFcn(app, @B_Solucion_2ButtonPushed, true);
            app.B_Solucion_2.Text = 'SOLUCION';
            app.B_Mostrar_2 = uibutton(app.GridLayout17, 'push');
            app.B_Mostrar_2.ButtonPushedFcn = createCallbackFcn(app, @B_Mostrar_2ButtonPushed, true);
            app.B_Mostrar_2.Text = 'MOSTRAR';
            app.P_Mostrar = uipanel(app.GridLayout);
            app.P_Mostrar.Visible = 'off';
            app.GridLayout3 = uigridlayout(app.P_Mostrar);
            app.P_Panel_1 = uipanel(app.GridLayout3);
            app.GridLayout6 = uigridlayout(app.P_Panel_1);
            app.I_Imagen_Panel = uiimage(app.GridLayout6);
            app.PANELLabel = uilabel(app.GridLayout6);
            app.PANELLabel.Text = 'PANEL';
            app.MODELOEditFieldLabel = uilabel(app.GridLayout6);
            app.MODELOEditFieldLabel.Text = 'MODELO';
            app.Fi_Modelo_Panel = uieditfield(app.GridLayout6, 'text');
            app.Fi_Modelo_Panel.Editable = 'off';
            app.POTENCIAEditFieldLabel = uilabel(app.GridLayout6);
            app.POTENCIAEditFieldLabel.Text = 'POTENCIA';
            app.Fi_Potencia_Panel = uieditfield(app.GridLayout6, 'numeric');
            app.Fi_Potencia_Panel.Editable = 'off';
            app.ANCHOLabel = uilabel(app.GridLayout6);
            app.ANCHOLabel.Text = 'ANCHO';
            app.Fi_Ancho_Panel = uieditfield(app.GridLayout6, 'numeric');
            app.Fi_Ancho_Panel.Editable = 'off';
            app.COSTOLabel = uilabel(app.GridLayout6);
            app.COSTOLabel.Text = 'COSTO';
            app.Fi_Costo_Panel = uieditfield(app.GridLayout6, 'numeric');
            app.Fi_Costo_Panel.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Panel.Editable = 'off';
            app.VOLTAJELabel = uilabel(app.GridLayout6);
            app.VOLTAJELabel.Text = 'VOLTAJE';
            app.Fi_Voltaje_Panel = uieditfield(app.GridLayout6, 'numeric');
            app.Fi_Voltaje_Panel.Editable = 'off';
            app.ALTOLabel = uilabel(app.GridLayout6);
            app.ALTOLabel.Text = 'ALTO';
            app.Fi_Alto_Panel = uieditfield(app.GridLayout6, 'numeric');
            app.Fi_Alto_Panel.Editable = 'off';
            app.TIEMPODELabel = uilabel(app.GridLayout6);
            app.TIEMPODELabel.Text = {'TIEMPO DE '; 'VIDA'};
            app.Fi_Tiempo_Vida_Panel = uieditfield(app.GridLayout6, 'numeric');
            app.Fi_Tiempo_Vida_Panel.Editable = 'off';
            app.P_Bateria_2 = uipanel(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.P_Bateria_2);
            app.I_Imagen_Bateria = uiimage(app.GridLayout4);
            app.BATERIALabel_3 = uilabel(app.GridLayout4);
            app.BATERIALabel_3.Text = 'BATERIA';
            app.MODELOEditFieldLabel_3 = uilabel(app.GridLayout4);
            app.MODELOEditFieldLabel_3.Text = 'MODELO';
            app.Fi_Modelo_Bateria = uieditfield(app.GridLayout4, 'text');
            app.Fi_Modelo_Bateria.Editable = 'off';
            app.CAPACIDADLabel = uilabel(app.GridLayout4);
            app.CAPACIDADLabel.Text = 'CAPACIDAD';
            app.Fi_Capacidad_Bateria = uieditfield(app.GridLayout4, 'numeric');
            app.Fi_Capacidad_Bateria.Editable = 'off';
            app.ANCHOLabel_2 = uilabel(app.GridLayout4);
            app.ANCHOLabel_2.Text = 'ANCHO';
            app.Fi_Ancho_Bateria = uieditfield(app.GridLayout4, 'numeric');
            app.Fi_Ancho_Bateria.Editable = 'off';
            app.COSTOLabel_2 = uilabel(app.GridLayout4);
            app.COSTOLabel_2.Text = 'COSTO';
            app.Fi_Costo_Bateria = uieditfield(app.GridLayout4, 'numeric');
            app.Fi_Costo_Bateria.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Bateria.Editable = 'off';
            app.VOLTAJELabel_2 = uilabel(app.GridLayout4);
            app.VOLTAJELabel_2.Text = 'LARGO';
            app.Fi_Largo_Bateria = uieditfield(app.GridLayout4, 'numeric');
            app.Fi_Largo_Bateria.Editable = 'off';
            app.ALTOLabel_2 = uilabel(app.GridLayout4);
            app.ALTOLabel_2.Text = 'ALTO';
            app.Fi_Alto_Bateria = uieditfield(app.GridLayout4, 'numeric');
            app.Fi_Alto_Bateria.Editable = 'off';
            app.TIEMPODELabel_2 = uilabel(app.GridLayout4);
            app.TIEMPODELabel_2.Text = 'VOLTAJE';
            app.Fi_Voltaje_Bateria = uieditfield(app.GridLayout4, 'numeric');
            app.Fi_Voltaje_Bateria.Editable = 'off';
            app.P_Panel_3 = uipanel(app.GridLayout3);
            app.GridLayout5 = uigridlayout(app.P_Panel_3);
            app.I_Imagen_Inversor = uiimage(app.GridLayout5);
            app.INVERSORLabel_3 = uilabel(app.GridLayout5);
            app.INVERSORLabel_3.Text = 'INVERSOR';
            app.MODELOEditFieldLabel_4 = uilabel(app.GridLayout5);
            app.MODELOEditFieldLabel_4.Text = 'MODELO';
            app.Fi_Modelo_Inversor = uieditfield(app.GridLayout5, 'text');
            app.POTENCIAEditFieldLabel_3 = uilabel(app.GridLayout5);
            app.POTENCIAEditFieldLabel_3.Text = 'POTENCIA';
            app.Fi_Potencia_Inversor = uieditfield(app.GridLayout5, 'numeric');
            app.Fi_Potencia_Inversor.Editable = 'off';
            app.ANCHOLabel_3 = uilabel(app.GridLayout5);
            app.ANCHOLabel_3.Text = 'VOLTAJE ADMISIBLE';
            app.Fi_VAdmisible_Inversor = uieditfield(app.GridLayout5, 'numeric');
            app.Fi_VAdmisible_Inversor.Editable = 'off';
            app.VOLTAJELabel_3 = uilabel(app.GridLayout5);
            app.VOLTAJELabel_3.Text = 'COSTO';
            app.Fi_Costo_Inversor = uieditfield(app.GridLayout5, 'numeric');
            app.Fi_Costo_Inversor.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Inversor.Editable = 'off';
            app.TIEMPODELabel_3 = uilabel(app.GridLayout5);
            app.TIEMPODELabel_3.Text = 'TIEMPO DE VIDA';
            app.Fi_Tiempo_Vida_Inversor = uieditfield(app.GridLayout5, 'numeric');
            app.Fi_Tiempo_Vida_Inversor.Editable = 'off';
            app.P_Solucion = uipanel(app.GridLayout);
            app.P_Solucion.Visible = 'off';
            app.GridLayout7 = uigridlayout(app.P_Solucion);
            app.Panel = uipanel(app.GridLayout7);
            app.GridLayout8 = uigridlayout(app.Panel);
            app.PANELLabel_2 = uilabel(app.GridLayout8);
            app.PANELLabel_2.Text = 'PANEL';
            app.NumPanel = uieditfield(app.GridLayout8, 'numeric');
            app.NumPanel.ValueDisplayFormat = '%.0f';
            app.MODELOEditFieldLabel_2 = uilabel(app.GridLayout8);
            app.MODELOEditFieldLabel_2.Text = 'MODELO';
            app.MODELOEditField = uieditfield(app.GridLayout8, 'text');
            app.MODELOEditField.Editable = 'off';
            app.Panel_2 = uipanel(app.GridLayout7);
            app.GridLayout9 = uigridlayout(app.Panel_2);
            app.BATERIALabel_2 = uilabel(app.GridLayout9);
            app.BATERIALabel_2.Text = 'BATERIA';
            app.NumBateria = uieditfield(app.GridLayout9, 'numeric');
            app.NumBateria.ValueDisplayFormat = '%.0f';
            app.MODELOEditField_2Label_2 = uilabel(app.GridLayout9);
            app.MODELOEditField_2Label_2.Text = 'MODELO';
            app.MODELOEditField_2 = uieditfield(app.GridLayout9, 'text');
            app.MODELOEditField_2.Editable = 'off';
            app.Panel_3 = uipanel(app.GridLayout7);
            app.GridLayout10 = uigridlayout(app.Panel_3);
            app.INVERSORLabel_2 = uilabel(app.GridLayout10);
            app.INVERSORLabel_2.Text = 'INVERSOR';
            app.NumInversor = uieditfield(app.GridLayout10, 'numeric');
            app.NumInversor.ValueDisplayFormat = '%.0f';
            app.MODELOEditField_3Label_2 = uilabel(app.GridLayout10);
            app.MODELOEditField_3Label_2.Text = 'MODELO';
            app.MODELOEditField_3 = uieditfield(app.GridLayout10, 'text');
            app.MODELOEditField_3.Editable = 'off';
            app.I_Imagen_Solucion = uiimage(app.GridLayout7);
            app.COSTOINSTALACIONEditFieldLabel = uilabel(app.GridLayout7);
            app.COSTOINSTALACIONEditFieldLabel.Text = 'COSTO INSTALACION';
            app.Fi_Costo_Instalacion_Solucion = uieditfield(app.GridLayout7, 'numeric');
            app.Fi_Costo_Instalacion_Solucion.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Instalacion_Solucion.AllowEmpty = 'on';
            app.Fi_Costo_Instalacion_Solucion.Editable = 'off';
            app.COSTOCOMPRAEditFieldLabel = uilabel(app.GridLayout7);
            app.COSTOCOMPRAEditFieldLabel.Text = 'COSTO COMPRA';
            app.Fi_Costo_Compra_Solucion = uieditfield(app.GridLayout7, 'numeric');
            app.Fi_Costo_Compra_Solucion.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Compra_Solucion.AllowEmpty = 'on';
            app.Fi_Costo_Compra_Solucion.Editable = 'off';
            app.COSTOMANTENIMIENTOLabel = uilabel(app.GridLayout7);
            app.COSTOMANTENIMIENTOLabel.Text = {'COSTO'; 'MANTENIMIENTO'};
            app.Fi_Costo_Mantenimiento_Solucion = uieditfield(app.GridLayout7, 'numeric');
            app.Fi_Costo_Mantenimiento_Solucion.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Mantenimiento_Solucion.AllowEmpty = 'on';
            app.Fi_Costo_Mantenimiento_Solucion.Editable = 'off';
            app.COSTOTOTALLabel = uilabel(app.GridLayout7);
            app.COSTOTOTALLabel.Text = 'COSTO TOTAL';
            app.Fi_Costo_Total_Solucion = uieditfield(app.GridLayout7, 'numeric');
            app.Fi_Costo_Total_Solucion.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Total_Solucion.AllowEmpty = 'on';
            app.Fi_Costo_Total_Solucion.Editable = 'off';
            app.P_Mostrar_Hibrido = uipanel(app.GridLayout);
            app.P_Mostrar_Hibrido.Visible = 'off';
            app.GridLayout11 = uigridlayout(app.P_Mostrar_Hibrido);
            app.P_Panel_4 = uipanel(app.GridLayout11);
            app.GridLayout15 = uigridlayout(app.P_Panel_4);
            app.I_Imagen_Panel_2 = uiimage(app.GridLayout15);
            app.PANELLabel_3 = uilabel(app.GridLayout15);
            app.PANELLabel_3.Text = 'PANEL';
            app.MODELOEditFieldLabel_5 = uilabel(app.GridLayout15);
            app.MODELOEditFieldLabel_5.Text = 'MODELO';
            app.Fi_Modelo_Panel_2 = uieditfield(app.GridLayout15, 'text');
            app.Fi_Modelo_Panel_2.Editable = 'off';
            app.POTENCIAEditFieldLabel_4 = uilabel(app.GridLayout15);
            app.POTENCIAEditFieldLabel_4.Text = 'POTENCIA';
            app.Fi_Potencia_Panel_2 = uieditfield(app.GridLayout15, 'numeric');
            app.Fi_Potencia_Panel_2.Editable = 'off';
            app.ANCHOLabel_4 = uilabel(app.GridLayout15);
            app.ANCHOLabel_4.Text = 'ANCHO';
            app.Fi_Ancho_Panel_2 = uieditfield(app.GridLayout15, 'numeric');
            app.Fi_Ancho_Panel_2.Editable = 'off';
            app.COSTOLabel_3 = uilabel(app.GridLayout15);
            app.COSTOLabel_3.Text = 'COSTO';
            app.Fi_Costo_Panel_2 = uieditfield(app.GridLayout15, 'numeric');
            app.Fi_Costo_Panel_2.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Panel_2.Editable = 'off';
            app.VOLTAJELabel_4 = uilabel(app.GridLayout15);
            app.VOLTAJELabel_4.Text = 'VOLTAJE';
            app.Fi_Voltaje_Panel_2 = uieditfield(app.GridLayout15, 'numeric');
            app.Fi_Voltaje_Panel_2.Editable = 'off';
            app.ALTOLabel_3 = uilabel(app.GridLayout15);
            app.ALTOLabel_3.Text = 'ALTO';
            app.Fi_Alto_Panel_2 = uieditfield(app.GridLayout15, 'numeric');
            app.Fi_Alto_Panel_2.Editable = 'off';
            app.TIEMPODELabel_4 = uilabel(app.GridLayout15);
            app.TIEMPODELabel_4.Text = {'TIEMPO DE '; 'VIDA'};
            app.Fi_Tiempo_Vida_Panel_2 = uieditfield(app.GridLayout15, 'numeric');
            app.Fi_Tiempo_Vida_Panel_2.Editable = 'off';
            app.P_Panel_5 = uipanel(app.GridLayout11);
            app.GridLayout16 = uigridlayout(app.P_Panel_5);
            app.I_Imagen_Inversor_2 = uiimage(app.GridLayout16);
            app.INVERSORLabel_4 = uilabel(app.GridLayout16);
            app.INVERSORLabel_4.Text = 'INVERSOR';
            app.MODELOEditFieldLabel_7 = uilabel(app.GridLayout16);
            app.MODELOEditFieldLabel_7.Text = 'MODELO';
            app.Fi_Modelo_Inversor_2 = uieditfield(app.GridLayout16, 'text');
            app.POTENCIAEditFieldLabel_5 = uilabel(app.GridLayout16);
            app.POTENCIAEditFieldLabel_5.Text = 'POTENCIA';
            app.Fi_Potencia_Inversor_2 = uieditfield(app.GridLayout16, 'numeric');
            app.Fi_Potencia_Inversor_2.Editable = 'off';
            app.ANCHOLabel_6 = uilabel(app.GridLayout16);
            app.ANCHOLabel_6.Text = 'V. ADMISIBLE';
            app.Fi_VAdmisible_Inversor_2 = uieditfield(app.GridLayout16, 'numeric');
            app.Fi_VAdmisible_Inversor_2.Editable = 'off';
            app.VOLTAJELabel_6 = uilabel(app.GridLayout16);
            app.VOLTAJELabel_6.Text = 'COSTO';
            app.Fi_Costo_Inversor_2 = uieditfield(app.GridLayout16, 'numeric');
            app.Fi_Costo_Inversor_2.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Inversor_2.Editable = 'off';
            app.TIEMPODELabel_6 = uilabel(app.GridLayout16);
            app.TIEMPODELabel_6.Text = {'TIEMPO DE '; 'VIDA'};
            app.Fi_Tiempo_Vida_Inversor_2 = uieditfield(app.GridLayout16, 'numeric');
            app.Fi_Tiempo_Vida_Inversor_2.Editable = 'off';
            app.SistemaHbridoLabel = uilabel(app.GridLayout11);
            app.SistemaHbridoLabel.Text = 'Sistema Híbrido';
            app.P_Solucion_Hibrido = uipanel(app.GridLayout);
            app.P_Solucion_Hibrido.Visible = 'off';
            app.GridLayout12 = uigridlayout(app.P_Solucion_Hibrido);
            app.I_Imagen_Solucion_2 = uiimage(app.GridLayout12);
            app.Panel_4 = uipanel(app.GridLayout12);
            app.GridLayout13 = uigridlayout(app.Panel_4);
            app.PANELLabel_4 = uilabel(app.GridLayout13);
            app.PANELLabel_4.Text = 'PANEL';
            app.NumPanelH = uieditfield(app.GridLayout13, 'numeric');
            app.NumPanelH.ValueDisplayFormat = '%.0f';
            app.MODELOEditField_4Label = uilabel(app.GridLayout13);
            app.MODELOEditField_4Label.Text = 'MODELO';
            app.MODELOEditField_4 = uieditfield(app.GridLayout13, 'text');
            app.MODELOEditField_4.Editable = 'off';
            app.Panel_6 = uipanel(app.GridLayout12);
            app.GridLayout14 = uigridlayout(app.Panel_6);
            app.INVERSORLabel_5 = uilabel(app.GridLayout14);
            app.INVERSORLabel_5.Text = 'INVERSOR';
            app.NumInversorH = uieditfield(app.GridLayout14, 'numeric');
            app.NumInversorH.ValueDisplayFormat = '%.0f';
            app.MODELOEditField_6Label = uilabel(app.GridLayout14);
            app.MODELOEditField_6Label.Text = 'MODELO';
            app.MODELOEditField_6 = uieditfield(app.GridLayout14, 'text');
            app.MODELOEditField_6.Editable = 'off';
            app.SistemaHbridoLabel_2 = uilabel(app.GridLayout12);
            app.SistemaHbridoLabel_2.Text = 'Sistema Híbrido';
            app.COSTOINSTALACIONEditFieldLabel_2 = uilabel(app.GridLayout12);
            app.COSTOINSTALACIONEditFieldLabel_2.Text = 'COSTO INSTALACION';
            app.Fi_Costo_Instalacion_Solucion_2 = uieditfield(app.GridLayout12, 'numeric');
            app.Fi_Costo_Instalacion_Solucion_2.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Instalacion_Solucion_2.AllowEmpty = 'on';
            app.Fi_Costo_Instalacion_Solucion_2.Editable = 'off';
            app.COSTOCOMPRAEditFieldLabel_2 = uilabel(app.GridLayout12);
            app.COSTOCOMPRAEditFieldLabel_2.Text = 'COSTO COMPRA';
            app.Fi_Costo_Compra_Solucion_2 = uieditfield(app.GridLayout12, 'numeric');
            app.Fi_Costo_Compra_Solucion_2.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Compra_Solucion_2.AllowEmpty = 'on';
            app.Fi_Costo_Compra_Solucion_2.Editable = 'off';
            app.COSTOMANTENIMIENTOLabel_2 = uilabel(app.GridLayout12);
            app.COSTOMANTENIMIENTOLabel_2.Text = {'COSTO'; 'MANTENIMIENTO'};
            app.Fi_Costo_Mantenimiento_Solucion_2 = uieditfield(app.GridLayout12, 'numeric');
            app.Fi_Costo_Mantenimiento_Solucion_2.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Mantenimiento_Solucion_2.AllowEmpty = 'on';
            app.Fi_Costo_Mantenimiento_Solucion_2.Editable = 'off';
            app.COSTOTOTALLabel_2 = uilabel(app.GridLayout12);
            app.COSTOTOTALLabel_2.Text = 'COSTO TOTAL';
            app.Fi_Costo_Total_Solucion_2 = uieditfield(app.GridLayout12, 'numeric');
            app.Fi_Costo_Total_Solucion_2.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Total_Solucion_2.AllowEmpty = 'on';
            app.Fi_Costo_Total_Solucion_2.Editable = 'off';
            app.P_inicio_Instalacion = uipanel(app.GridLayout);
            app.P_inicio_Instalacion.Visible = 'off';
            app.GridLayout2 = uigridlayout(app.P_inicio_Instalacion);
            app.B_System_YY = uibutton(app.GridLayout2, 'push');
            app.B_System_YY.ButtonPushedFcn = createCallbackFcn(app, @B_System_YYButtonPushed, true);
            app.B_System_YY.Text = 'Sistema Aislado';
            app.B_System_Hibrido = uibutton(app.GridLayout2, 'push');
            app.B_System_Hibrido.ButtonPushedFcn = createCallbackFcn(app, @B_System_HibridoButtonPushed, true);
            app.B_System_Hibrido.Text = 'Sistema Hibrido';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = M2_Mockup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end