classdef M3_Mockup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        P_Main                  matlab.ui.Figure
        E_M3_Value_Costo        matlab.ui.control.Label
        E_M3_Label_Costo        matlab.ui.control.Label
        E_M3_Value_BC           matlab.ui.control.Label
        E_M3_Label_BC           matlab.ui.control.Label
        E_M3_Value_Total        matlab.ui.control.Label
        E_M3_Label_Total        matlab.ui.control.Label
        E_M3_Value_TIR          matlab.ui.control.Label
        E_M3_Label_TIR          matlab.ui.control.Label
        SimularButton           matlab.ui.control.Button
        E_M3_Value_ROI          matlab.ui.control.Label
        E_M3_Label_ROI          matlab.ui.control.Label
        E_M3_Value_PRI          matlab.ui.control.Label
        E_M3_Label_PRI          matlab.ui.control.Label
        P_Botones_Inicio        matlab.ui.container.Panel
        Container_Botones       matlab.ui.container.GridLayout
        B_EnergiaProducida_3    matlab.ui.control.Button
        B_Instalacion_2         matlab.ui.control.Button
        B_CalculadoraConsumo_1  matlab.ui.control.Button
        CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel  matlab.ui.control.Label
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: B_CalculadoraConsumo_1
        function CalculadoraConsumoView_1(app, event)
            FC_showView(app,"1")
        end

        % Callback function
        function IngresarRecibosView_1(app, event)
            FC_showView(app,"1.1")
        end

        % Callback function
        function RegresarReciboElectrodomesticoview(app, event)
            FC_showView(app,"1.1.2")
        end

        % Callback function
        function ReciboAnadirView(app, event)
            FC_showView(app,"1.1.3")
        end

        % Button down function: UIAxes
        function mostrarComparativa(app, event)
            
        end

        % Button pushed function: SimularButton
        function FC_ActualizarCalculadoraInversion(app, event)
            calc_inv = C_M3_CalculadoraInversion(25,120,2);
            calc_inv = calc_inv.simular();

            table = calc_inv.get_resultado();
            plot(app.UIAxes,table);

            table2 = calc_inv.get_comparacion();
            plot(app.UIAxes_2, table2);

            ROI = calc_inv.get_ROI();
            app.E_M3_Value_ROI.Text = "$ " + ROI;

            TIR = calc_inv.get_TIR();
            app.E_M3_Value_TIR.Text = "% " + (TIR * 100);
            
            PRI = calc_inv.get_PRI();
            app.E_M3_Value_PRI.Text = PRI + " años";

            Total = calc_inv.get_BeneficioTotal();
            app.E_M3_Value_Total.Text = "$ " + Total;

            BC = calc_inv.get_BC();
            app.E_M3_Value_BC.Text = "% " + BC * 100;

            Costo = calc_inv.get_Costo();
            app.E_M3_Value_Costo.Text = "$ " + Costo;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.P_Main = uifigure('Visible', 'off');
            app.P_Main.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.P_Main);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @mostrarComparativa, true);
            app.UIAxes_2 = uiaxes(app.P_Main);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel = uilabel(app.P_Main);
            app.CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel.Text = 'Calculadora de ROI en la implementación de energía solar vs eléctrica';
            app.P_Botones_Inicio = uipanel(app.P_Main);
            app.Container_Botones = uigridlayout(app.P_Botones_Inicio);
            app.B_CalculadoraConsumo_1 = uibutton(app.Container_Botones, 'push');
            app.B_CalculadoraConsumo_1.ButtonPushedFcn = createCallbackFcn(app, @CalculadoraConsumoView_1, true);
            app.B_CalculadoraConsumo_1.Text = 'Calculadora de Consumo';
            app.B_Instalacion_2 = uibutton(app.Container_Botones, 'push');
            app.B_Instalacion_2.Text = 'Instalación';
            app.B_EnergiaProducida_3 = uibutton(app.Container_Botones, 'push');
            app.B_EnergiaProducida_3.Text = 'Energía Producida';
            app.E_M3_Label_PRI = uilabel(app.P_Main);
            app.E_M3_Label_PRI.Text = 'Años de Retorno';
            app.E_M3_Value_PRI = uilabel(app.P_Main);
            app.E_M3_Value_PRI.Text = '#meses';
            app.E_M3_Label_ROI = uilabel(app.P_Main);
            app.E_M3_Label_ROI.Text = 'Retorno de Inversion';
            app.E_M3_Value_ROI = uilabel(app.P_Main);
            app.E_M3_Value_ROI.Text = '$soles';
            app.SimularButton = uibutton(app.P_Main, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @FC_ActualizarCalculadoraInversion, true);
            app.SimularButton.Text = 'Simular';
            app.E_M3_Label_TIR = uilabel(app.P_Main);
            app.E_M3_Label_TIR.Text = 'Tasa de Retorno de Inversion';
            app.E_M3_Value_TIR = uilabel(app.P_Main);
            app.E_M3_Value_TIR.Text = '% ....';
            app.E_M3_Label_Total = uilabel(app.P_Main);
            app.E_M3_Label_Total.Text = 'Beneficio Total';
            app.E_M3_Value_Total = uilabel(app.P_Main);
            app.E_M3_Value_Total.Text = '$soles';
            app.E_M3_Label_BC = uilabel(app.P_Main);
            app.E_M3_Label_BC.Text = 'Beneficio / Costo';
            app.E_M3_Value_BC = uilabel(app.P_Main);
            app.E_M3_Value_BC.Text = '$soles';
            app.E_M3_Label_Costo = uilabel(app.P_Main);
            app.E_M3_Label_Costo.Text = 'Costo de Inversion';
            app.E_M3_Value_Costo = uilabel(app.P_Main);
            app.E_M3_Value_Costo.Text = '$soles';
            % Show the figure after all components are created
            app.P_Main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = M3_Mockup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.P_Main)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.P_Main)
        end
    end
end