classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image9                          matlab.ui.control.Image
        Panel_4                         matlab.ui.container.Panel
        CalcularconsumoButton           matlab.ui.control.Button
        AnadirElectrodomesticoT         matlab.ui.control.Button
        EliminartodoButton              matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        Panel_6                         matlab.ui.container.Panel
        ModificarButton                 matlab.ui.control.Button
        ModificarP_DiasEditField_2      matlab.ui.control.NumericEditField
        DasLabel_2                      matlab.ui.control.Label
        ModificarP_HorasEditField_2     matlab.ui.control.NumericEditField
        HorasLabel_2                    matlab.ui.control.Label
        TipoDropDown_2                  matlab.ui.control.DropDown
        TipoDropDown_2Label             matlab.ui.control.Label
        ModificarP_ConsumoEditField     matlab.ui.control.NumericEditField
        ConsumoEditFieldLabel           matlab.ui.control.Label
        SeleccioneDropDown              matlab.ui.control.DropDown
        ModificarP_ActualizarButton     matlab.ui.control.Button
        EliminartodoButton_2            matlab.ui.control.Button
        LimpiarButton_2                 matlab.ui.control.Button
        Image8                          matlab.ui.control.Image
        Button                          matlab.ui.control.Button
        UITable_2                       matlab.ui.control.Table
        ElectrodomsticoLabel            matlab.ui.control.Label
        Image7_4                        matlab.ui.control.Image
        Panel_5                         matlab.ui.container.Panel
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        VarianzadeconsumoLabel          matlab.ui.control.Label
        PotenciapicoLabel               matlab.ui.control.Label
        ConsumodiariopromedioLabel      matlab.ui.control.Label
        ConsumomensualLabel             matlab.ui.control.Label
        ParmetrosLabel                  matlab.ui.control.Label
        Image7_2                        matlab.ui.control.Image
        ClculoLabel                     matlab.ui.control.Label
        Image7                          matlab.ui.control.Image
        Panel_3                         matlab.ui.container.Panel
        ModificarModelosButton          matlab.ui.control.Button
        AadirButton                     matlab.ui.control.Button
        GestionarButton                 matlab.ui.control.Button
        CalcularConsumoButton           matlab.ui.control.Button
        EliminarButton                  matlab.ui.control.Button
        Panel2                          matlab.ui.container.Panel
        Image2_4                        matlab.ui.control.Image
        Image2_3                        matlab.ui.control.Image
        Image2_2                        matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        CostototalEditField             matlab.ui.control.EditField
        CostototalEditFieldLabel        matlab.ui.control.Label
        TerrenonecesarioEditField       matlab.ui.control.EditField
        TerrenonecesarioEditFieldLabel  matlab.ui.control.Label
        PanelesnecesariosEditField      matlab.ui.control.EditField
        PanelesnecesariosEditFieldLabel  matlab.ui.control.Label
        BateriasnecesariasEditField     matlab.ui.control.EditField
        BateriasnecesariasEditFieldLabel  matlab.ui.control.Label
        CostoDeInstalacion              matlab.ui.control.Label
        IngreseConsumo                  matlab.ui.control.NumericEditField
        IngresesuconsumomensualLabel    matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        EnergaProducidaButton           matlab.ui.control.Button
        InstalacionButton               matlab.ui.control.Button
        CalculadoradeConsumoButton      matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        ModelosLabel                    matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        DasLabel                        matlab.ui.control.Label
        HorasLabel                      matlab.ui.control.Label
        WattsLabel                      matlab.ui.control.Label
        CantidadDiasEditField           matlab.ui.control.NumericEditField
        UsoporsemanaLabel               matlab.ui.control.Label
        UsoEnHorasEditField             matlab.ui.control.NumericEditField
        UsopordaLabel                   matlab.ui.control.Label
        PotenciaEnWattsEditField        matlab.ui.control.NumericEditField
        PotenciaLabel                   matlab.ui.control.Label
        NombreEditField                 matlab.ui.control.EditField
        NombreEditFieldLabel            matlab.ui.control.Label
        TipoDropDown                    matlab.ui.control.DropDown
        TipoDropDownLabel               matlab.ui.control.Label
        AgregarButton                   matlab.ui.control.Button
        CalcularPotenciaButton          matlab.ui.control.Button
        ResultadoLabel                  matlab.ui.control.Label
        CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)

        %crea un usuario con sus parametros (id, nombre, direccion)
        usuario = C_Usuario(2, "Jeremy", "Arequipa");
    end


    methods (Access = public)
        function limpiarValores(app)
            %hace que el valor de la potencia, uso en horas, cantidad de dias de uso,
            %el tipo y el nombre vuelvan a sus valores iniciales
            app.PotenciaEnWattsEditField.Value = 0;
            app.UsoEnHorasEditField.Value = 0;
            app.CantidadDiasEditField.Value = 0;
            app.TipoDropDown.Value = "Esencial";
            app.NombreEditField.Value = "";
        end
        function limpiarValores_ModificarModelos(app)
            %hace que el valor de la potencia, uso en horas, cantidad de dias de uso,
            %el tipo y el nombre vuelvan a sus valores iniciales
            app.ModificarP_ConsumoEditField.Value = 0;
            app.ModificarP_DiasEditField_2.Value = 0;
            app.ModificarP_HorasEditField_2.Value = 0;
            app.ModificarP_DiasEditField_2.Value = 0;
            app.TipoDropDown_2.Value = "Esencial";
        end
        function ActualizarCallback(app)
          try
            consumo = app.PotenciaEnWattsEditField.Value;%captura el consumo
            horas = app.UsoEnHorasEditField.Value;%captura el uso en horas
            dias = app.CantidadDiasEditField.Value;%captura el tiempo en dias
            tipo = app.TipoDropDown.Value;%captura el tipo de electrodomestico
            nombre = app.NombreEditField.Value;%captura el nombre}
            %crea un electrodomestico con su constructor, se pasan como
            %argumento los valores obtenidos previamente
            equipo = C_Electrodomestico(nombre, consumo, horas, dias, tipo);
            %modifica un equipo(electrodomestico) del usuario a traves del metodo
            %Mod_Electrodomestico() se le pasa por argumente el equipo
            app.usuario = app.usuario.Mod_Electrodomestico(equipo);
            %muestra mensaje en caso no halla error
            message = "El consumo actualizado a: "+ equipo.ConsumoTotal(1)+" Watts al mes";
            %cambia el valor del boton de Actualizar a Agregar
            app.AgregarButton.Text = "Agregar";
            %cambia el callback a AgregarCallback
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarCallback, true);
            %habilita los demas botones y hace no visisble a SeleccioneDropDown
            %ya que ya se modifico el valor
            app.NombreEditField.Editable = "on";
            app.SeleccioneDropDown.Visible = "off";
            app.ModificarButton.Enable = "on";
            app.EliminartodoButton.Enable = "on";
            app.CalcularPotenciaButton.Enable = "on";
            app.EliminarButton.Enable = "on";
            limpiarValores(app);%limpia los campos
          catch exception%captura excepciones
              message = exception.message;
          end
          app.ResultadoLabel.Text = message;%muestra el mensaje
        end
    end
    
    methods (Access = private)
            % Método para mostrar la interfaz de instalación
        function showInstallationView(app)
            % Ocultar los componentes actuales
            app.Panel.Visible = 'off';        
            % Mostrar los componentes de instalación
            app.Panel2.Visible = 'on';   % Panel de instalación
            app.Panel_3.Visible = 'off'; % Panel que contiene botones añadir,gestionar,etc.
            app.Panel_4.Visible = 'off'; % Panel con la tabla 
            app.Panel_5.Visible = 'off'; % Panel de calcular consumo
            app.Panel_6.Visible = 'off'; % Panel de Modificar modelos
        end
            % Método para mostrar interfaz de calculadora
        function showCalculatorView(app)
            app.Panel.Visible = 'on';
            app.Panel2.Visible = 'off'; 
            app.Panel_3.Visible = 'on'; % Panel que contiene botones añadir,gestionar,etc.
            app.Panel_4.Visible = 'off'; % Tabla
        end

        function showCalculatorViewAnadir(app)
            app.Panel.Visible = 'on';
            app.Panel2.Visible = 'off'; 
            app.Panel_3.Visible = 'on';   % Panel que contiene botones añadir,gestionar,etc.
            app.Panel_4.Visible = 'off';  % Panel que contiene la tabla
            app.Panel_5.Visible = 'off';  % Panel de calcular consumo
            app.Panel_6.Visible = 'off';  % Panel de Modificar modelos
        end

        function showCalculatorViewGestionar(app)
            app.Panel.Visible = 'off';
            app.Panel2.Visible = 'off'; 
            app.Panel_4.Visible = 'on';   % Panel que contiene la tabla
            app.Panel_5.Visible = 'off';  % Panel de calcular consumo
            app.Panel_6.Visible = 'off';  % Panel de Modificar modelos
        end

        function showCalculatorViewCalcularConsumo(app)
            app.Panel.Visible = 'off';
            app.Panel2.Visible = 'off';  
            app.Panel_4.Visible = 'off'; % Panel con la tabla
            app.Panel_5.Visible = 'on';  % Panel de calcular consumo
            app.Panel_6.Visible = 'off'; % Panel de Modificar modelos
        end

        function showCalculatorViewModificarModelos(app)
            app.Panel.Visible = 'off';
            app.Panel2.Visible = 'off'; 
            app.Panel_4.Visible = 'off'; % Panel con la tabla
            app.Panel_5.Visible = 'off'; % Panel de calcular consumo
            app.Panel_6.Visible = 'on';  % Panel de Modificar modelos
        end

        % Método para manejar el guardado de datos en la instalación
        function SaveButtonPushed(app, event)
            % Código para guardar los datos
            disp('Datos guardados');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AgregarButton
        function AgregarCallback(app, event)
            app.ResultadoLabel.Visible = "on";

            function actualizarTabla = actualizarTabla(app, register)
                
            end

            try
                % Obtener los valores de los campos de entrada
                consumo = app.PotenciaEnWattsEditField.Value;
                horas = app.UsoEnHorasEditField.Value;
                dias = app.CantidadDiasEditField.Value;
                tipo = app.TipoDropDown.Value;
                nombre = app.NombreEditField.Value;
                
                % Crear el nuevo electrodoméstico
                producto = C_Electrodomestico(nombre, consumo, horas, dias, tipo);
                app.usuario = app.usuario.Add_Electrodomestico(producto);
                
                % Conectar a la base de datos
                conn = conectarBD();
                
                % Crear consulta SQL para insertar datos
                sqlquery = sprintf("INSERT INTO electrodomesticos (nombre, consumo, horas, dias, tipo) VALUES ('%s', %d, %d, %d, '%s')", nombre, consumo, horas, dias, tipo);
                
                % Ejecutar la consulta
                exec(conn, sqlquery);
                
                % Cerrar conexión
                close(conn);
                
                
                % Actualizar la tabla en la UI
                actualizarTabla(app, producto);
                
                % Limpiar los campos de entrada
                
                
                message = "El consumo de " + nombre + " es: " + producto.ConsumoTotal(1) + " Watts al mes";
                % Obtener los datos actuales de las tablas
                datosActuales1 = app.UITable.Data;
                datosActuales2 = app.UITable_2.Data;
    
                % Crear una nueva fila con los datos ingresados
                nuevaFila1 = {nombre, consumo, horas, dias, tipo};
                nuevaFila2 = {nombre, consumo, tipo}; 
                % Añadir la nueva fila a los datos actuales
                nuevosDatos = [datosActuales1; nuevaFila1];
                nuevosDatos2 = [datosActuales2; nuevaFila2];
                % Asignar los nuevos datos a la tabla
                app.UITable.Data = nuevosDatos;
                app.UITable_2.Data = nuevosDatos2;
                
                    % Verificar si las tablas están vacías y ajustar el formato si es necesario
                    if isempty(datosActuales1)
                        datosActuales1 = nuevaFila1;
                    else
                        datosActuales1 = [datosActuales1; nuevaFila1];
                    end
                    
                    if isempty(datosActuales2)
                        datosActuales2 = nuevaFila2;
                    else
                        datosActuales2 = [datosActuales2; nuevaFila2];
                    end

                % Habilitación de botones
                app.ModificarButton.Enable = "on";
                app.EliminartodoButton.Enable = "on";
                app.CalcularPotenciaButton.Enable = "on";
                app.EliminarButton.Enable = "on";
                app.SeleccioneDropDown.Visible = "off";
                app.EliminartodoButton_2.Enable = "on"; 
                app.LimpiarButton_2.Enable = "on";
                limpiarValores(app);

            catch exception
                message = exception.message;
                app.ResultadoLabel.Text = message;
                app.ResultadoLabel.FontColor = [1, 0, 0];  % RGB para rojo
            end
            app.ResultadoLabel.Text = message;               
        end

        % Button pushed function: CalcularPotenciaButton
        function CalcularTotalCallback(app, event)
            total = app.usuario.ConsumoTotal(1);
            consumoTotalStr = num2str("El total es: "+total+" Watts");
            app.ResultadoLabel.Text = consumoTotalStr;
        end

        % Button pushed function: EliminartodoButton
        function LimpiarCallback(app, event)
            %el arreglo de electrodomesticos del usuario se hace vacio se
            % El arreglo de electrodomésticos del usuario se hace vacío, se comienza de nuevo
            app.usuario.electrodomesticos = C_Electrodomestico.empty;
           
            % Asignar un arreglo vacío a la tabla para eliminar todos los datos
            app.UITable.Data = {};
            app.UITable_2.Data = {};
            
            %deja habilitado solo el boton de agregar
            app.ResultadoLabel.Visible = "off";
            app.ModificarButton.Enable = "off";
            app.EliminartodoButton.Enable = "off";
            app.CalcularPotenciaButton.Enable = "off";
            app.EliminarButton.Enable = "off";
            limpiarValores(app);%limpia los valores
        end

        % Button pushed function: EliminarButton
        function EliminarCallback(app, event)
            %no funciona aun
           
            app.ResultadoLabel.Visible = 'on';
            try
                app.usuario = app.usuario.pop();
                message = "Eliminado el ultimo elemento";
                
                if isempty(app.usuario.electrodomesticos)
                    app.ModificarButton.Enable = 'off';
                    app.EliminartodoButton.Enable = 'off';
                    app.CalcularPotenciaButton.Enable = 'off';
                    app.EliminarButton.Enable = 'off';
                end
            catch exception
                message = exception.message;
            end
            app.ResultadoLabel.Text = message;


        end

        % Button pushed function: ModificarButton
        function ModificarCallback(app, event)
            len = length(app.usuario.electrodomesticos);
            nombresElectrodomesticos = strings(1, (len+1));
            nombresElectrodomesticos(1) = "---";
            for i = 1:len
                nombresElectrodomesticos(i+1) = app.usuario.electrodomesticos(i).nombre;
            end
            app.SeleccioneDropDown.Items = nombresElectrodomesticos;
            app.SeleccioneDropDown.Visible = "on";
        end

        % Clicked callback: SeleccioneDropDown
        function ModificarElectrodomesticoCallback(app, event)
            %deja habilitado solo el selector y el boton agregar
            app.ModificarButton.Enable = "off";
            app.EliminartodoButton.Enable = "off";
            app.CalcularPotenciaButton.Enable = "off";
            app.EliminarButton.Enable = "off";
            app.ModificarP_ActualizarButton.Enable = "on";
            value = app.SeleccioneDropDown.Value;
            if value == "---"
                %en caso se seleccione la opcion --- vuelve limpia los
                %campos lo cual imposibilita la actualizacion hasta que se
                %seleccione un elemento
                limpiarValores(app);
            else
                %recorre el arreglo de electrodomesticos
                for i = 1:length(app.usuario.electrodomesticos)
                    %obtiene el nombre del i-esimo electrodomestico y lo
                    %guarda en una variable nombre
                    nombre = app.usuario.electrodomesticos(i).nombre;
                    %comprueba si el nombre y el valor(la opcion
                    %seleccionada del dropdown) son iguales
                    if  strcmp(nombre, value)
                        %no se puede cambiar el nombre
                        app.NombreEditField.Editable = "off";
                        %se cambian los valores de los EditFields con
                        %respecto a los valores del equipo
                        app.ModificarP_ConsumoEditField.Value = app.usuario.electrodomesticos(i).potencia;
                        app.ModificarP_HorasEditField_2.Value = app.usuario.electrodomesticos(i).horas_dia;
                        app.ModificarP_DiasEditField_2.Value = app.usuario.electrodomesticos(i).dia_semana;
                        app.TipoDropDown_2.Value = app.usuario.electrodomesticos(i).tipo;
                        app.NombreEditField.Value = nombre;
                                     
                        % Actualiza los datos en la tabla
                        datosActuales = app.UITable.Data;
                        datosActuales_2 = app.UITable_2.Data;
                        
                        % Busca el índice de la fila que corresponde al electrodoméstico
                        for j = 1:size(datosActuales, 1)
                            if strcmp(datosActuales{j, 1}, nombre)
                                datosActuales{j, 2} = app.PotenciaEnWattsEditField.Value;
                                datosActuales{j, 3} = app.UsoEnHorasEditField.Value;
                                datosActuales{j, 4} = app.CantidadDiasEditField.Value;
                                datosActuales{j, 5} = app.TipoDropDown.Value;
                                break;
                            end
                        end
                        
                        % Actualiza los datos en UITable
                        app.UITable.Data = datosActuales;
            
                        % Actualiza los datos en UITable_2
                        for k = 1:size(datosActuales_2, 1)
                            if strcmp(datosActuales_2{k, 1}, nombre)
                                datosActuales_2{k, 2} = app.PotenciaEnWattsEditField.Value;
                                datosActuales_2{k, 3} = app.TipoDropDown.Value;
                                break;
                            end
                        end
            
                        % Actualiza los datos en UITable_2
                        app.UITable_2.Data = datosActuales_2;

                        break;
                    end
                end
            end
      
        end

        % Button pushed function: InstalacionButton
        function InstalacionButtonPushed(app, event)
            app.InstalacionButton.ButtonPushedFcn = createCallbackFcn(app, @InstalacionButtonPushed, true);        
            showInstallationView(app);
            
        end

        % Button pushed function: CalculadoradeConsumoButton
        function CalculadoradeConsumoButtonPushed(app, event)
            showCalculatorView(app);
        end

        % Double-clicked callback: ListBox
        function ListBoxDoubleClicked(app, event)
            item = event.InteractionInformation.Item;
            selectedValue = app.ListBox.Value;
            app.NombreEditField.Value = selectedValue;
        end

        % Button pushed function: AadirButton
        function AnadirBoton_Calculadora(app, event)
            showCalculatorViewAnadir(app);
            app.Panel_4.Visible = 'off';
            app.EliminartodoButton.Visible = 'on';
        end

        % Button pushed function: GestionarButton
        function GestionarBoton_Calculadora(app, event)
            showCalculatorViewGestionar(app);
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            
        end

        % Callback function
        function anadirElectrodomesticoT(app, event)
            showCalculatorView(app);
        end

        % Button pushed function: CalcularConsumoButton
        function Calcular_Consumo_Calculadora(app, event)
            showCalculatorViewCalcularConsumo(app);        
        end

        % Button pushed function: ModificarModelosButton
        function ModificarModelos_Calculadora(app, event)
            showCalculatorViewModificarModelos(app);
        end

        % Button pushed function: CalcularconsumoButton
        function CalcularconsumoButtonPushed(app, event)
            %Botón en Gestionar
            showCalculatorViewCalcularConsumo(app);
        end

        % Button pushed function: AnadirElectrodomesticoT
        function anadirElectrodomesticoGestionar(app, event)
            showCalculatorView(app);
        end

        % Button pushed function: ModificarP_ActualizarButton
        function ActualizarModificarModelos(app, event)
            %deja habilitado solo el selector y el boton agregar
            app.ModificarButton.Enable = "off";
            app.EliminartodoButton.Enable = "off";
            app.CalcularPotenciaButton.Enable = "off";
            app.EliminarButton.Enable = "off";
            value = app.SeleccioneDropDown.Value;
            if value == "---"
                %en caso se seleccione la opcion --- vuelve limpia los
                %campos lo cual imposibilita la actualizacion hasta que se
                %seleccione un elemento
                limpiarValores(app);
            else
                %recorre el arreglo de electrodomesticos
                for i = 1:length(app.usuario.electrodomesticos)
                    %obtiene el nombre del i-esimo electrodomestico y lo
                    %guarda en una variable nombre
                    nombre = app.usuario.electrodomesticos(i).nombre;
                    %comprueba si el nombre y el valor(la opcion
                    %seleccionada del dropdown) son iguales
                    if  strcmp(nombre, value)
                        %no se puede cambiar el nombre
                        app.NombreEditField.Editable = "off";
                        app.NombreEditField.Value = nombre;
                                     
                        % Actualiza los datos en la tabla
                        datosActuales = app.UITable.Data;
                        datosActuales_2 = app.UITable_2.Data;
                        
                        % Busca el índice de la fila que corresponde al electrodoméstico
                        for j = 1:size(datosActuales, 1)
                            if strcmp(datosActuales{j, 1}, nombre)
                                datosActuales{j, 2} = app.ModificarP_ConsumoEditField.Value;
                                datosActuales{j, 3} = app.ModificarP_HorasEditField_2.Value;
                                datosActuales{j, 4} = app.ModificarP_DiasEditField_2.Value;
                                datosActuales{j, 5} = app.TipoDropDown_2.Value;
                                break;
                            end
                        end
                        
                        % Actualiza los datos en UITable
                        app.UITable.Data = datosActuales;
            
                        % Actualiza los datos en UITable_2
                        for k = 1:size(datosActuales_2, 1)
                            if strcmp(datosActuales_2{k, 1}, nombre)
                                datosActuales_2{k, 2} = app.ModificarP_ConsumoEditField.Value;
                                datosActuales_2{k, 3} = app.TipoDropDown_2.Value;
                                break;
                            end
                        end
            
                        % Actualiza los datos en UITable_2
                        app.UITable_2.Data = datosActuales_2;
                        limpiarValores_ModificarModelos(app);
                        break;
                    end
                end
            end
      
   
        end

        % Button pushed function: LimpiarButton_2
        function Limpiar_ModificarModelos(app, event)
            limpiarValores_ModificarModelos(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'logo.jpg';
            app.CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel = uilabel(app.UIFigure);
            app.CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel.Text = 'Calculadora de ROI en la implementación de energía solar vs eléctrica';
            app.Panel = uipanel(app.UIFigure);
            app.ResultadoLabel = uilabel(app.Panel);
            app.ResultadoLabel.Visible = 'off';
            app.ResultadoLabel.Text = 'Resultado';
            app.CalcularPotenciaButton = uibutton(app.Panel, 'push');
            app.CalcularPotenciaButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularTotalCallback, true);
            app.CalcularPotenciaButton.Enable = 'off';
            app.CalcularPotenciaButton.Text = 'Calcular Potencia';
            app.AgregarButton = uibutton(app.Panel, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarCallback, true);
            app.AgregarButton.Text = 'Agregar';
            app.TipoDropDownLabel = uilabel(app.Panel);
            app.TipoDropDownLabel.Text = 'Tipo';
            app.TipoDropDown = uidropdown(app.Panel);
            app.TipoDropDown.Items = {'Esencial', 'No Esencial', 'Altamente Esencial'};
            app.TipoDropDown.Value = 'Esencial';
            app.NombreEditFieldLabel = uilabel(app.Panel);
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.NombreEditField = uieditfield(app.Panel, 'text');
            app.PotenciaLabel = uilabel(app.Panel);
            app.PotenciaLabel.Text = 'Potencia';
            app.PotenciaEnWattsEditField = uieditfield(app.Panel, 'numeric');
            app.UsopordaLabel = uilabel(app.Panel);
            app.UsopordaLabel.Text = 'Uso por día';
            app.UsoEnHorasEditField = uieditfield(app.Panel, 'numeric');
            app.UsoporsemanaLabel = uilabel(app.Panel);
            app.UsoporsemanaLabel.Text = 'Uso por semana';
            app.CantidadDiasEditField = uieditfield(app.Panel, 'numeric');
            app.WattsLabel = uilabel(app.Panel);
            app.WattsLabel.Text = 'Watts';
            app.HorasLabel = uilabel(app.Panel);
            app.HorasLabel.Text = 'Horas';
            app.DasLabel = uilabel(app.Panel);
            app.DasLabel.Text = 'Días';
            app.ListBox = uilistbox(app.Panel);
            app.ListBox.Items = {'Adornos', 'Aire acondicionado', 'Altavoces', 'Consola', 'Computadora', 'Foco', 'Televisión'};
            app.ListBox.DoubleClickedFcn = createCallbackFcn(app, @ListBoxDoubleClicked, true);
            app.ListBox.Value = 'Televisión';
            app.ModelosLabel = uilabel(app.Panel);
            app.ModelosLabel.Text = 'Modelos';
            app.Panel_2 = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel_2);
            app.CalculadoradeConsumoButton = uibutton(app.GridLayout, 'push');
            app.CalculadoradeConsumoButton.ButtonPushedFcn = createCallbackFcn(app, @CalculadoradeConsumoButtonPushed, true);
            app.CalculadoradeConsumoButton.Icon = 'calc.png';
            app.CalculadoradeConsumoButton.Text = 'Calculadora de Consumo';
            app.InstalacionButton = uibutton(app.GridLayout, 'push');
            app.InstalacionButton.ButtonPushedFcn = createCallbackFcn(app, @InstalacionButtonPushed, true);
            app.InstalacionButton.Icon = 'panel.png';
            app.InstalacionButton.Text = 'Instalación';
            app.EnergaProducidaButton = uibutton(app.GridLayout, 'push');
            app.EnergaProducidaButton.Icon = 'energia.png';
            app.EnergaProducidaButton.Text = 'Energía Producida';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Visible = 'off';
            app.IngresesuconsumomensualLabel = uilabel(app.Panel2);
            app.IngresesuconsumomensualLabel.Text = 'Ingrese su consumo mensual';
            app.IngreseConsumo = uieditfield(app.Panel2, 'numeric');
            app.CostoDeInstalacion = uilabel(app.Panel2);
            app.CostoDeInstalacion.Text = 'Costo de Instalacion';
            app.BateriasnecesariasEditFieldLabel = uilabel(app.Panel2);
            app.BateriasnecesariasEditFieldLabel.Text = 'Baterias necesarias';
            app.BateriasnecesariasEditField = uieditfield(app.Panel2, 'text');
            app.PanelesnecesariosEditFieldLabel = uilabel(app.Panel2);
            app.PanelesnecesariosEditFieldLabel.Text = 'Paneles necesarios';
            app.PanelesnecesariosEditField = uieditfield(app.Panel2, 'text');
            app.TerrenonecesarioEditFieldLabel = uilabel(app.Panel2);
            app.TerrenonecesarioEditFieldLabel.Text = 'Terreno necesario';
            app.TerrenonecesarioEditField = uieditfield(app.Panel2, 'text');
            app.CostototalEditFieldLabel = uilabel(app.Panel2);
            app.CostototalEditFieldLabel.Text = 'Costo total';
            app.CostototalEditField = uieditfield(app.Panel2, 'text');
            app.Image2 = uiimage(app.Panel2);
            app.Image2.ImageSource = 'bateria.png';
            app.Image2_2 = uiimage(app.Panel2);
            app.Image2_2.ImageSource = 'panelSolar.png';
            app.Image2_3 = uiimage(app.Panel2);
            app.Image2_3.ImageSource = 'terreno.png';
            app.Image2_4 = uiimage(app.Panel2);
            app.Image2_4.ImageSource = 'dineroSimbolo.png';
            app.EliminarButton = uibutton(app.UIFigure, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarCallback, true);
            app.EliminarButton.Enable = 'off';
            app.EliminarButton.Text = 'Eliminar';
            app.Panel_3 = uipanel(app.UIFigure);
            app.CalcularConsumoButton = uibutton(app.Panel_3, 'push');
            app.CalcularConsumoButton.ButtonPushedFcn = createCallbackFcn(app, @Calcular_Consumo_Calculadora, true);
            app.CalcularConsumoButton.Text = {'Calcular '; 'Consumo'};
            app.GestionarButton = uibutton(app.Panel_3, 'push');
            app.GestionarButton.ButtonPushedFcn = createCallbackFcn(app, @GestionarBoton_Calculadora, true);
            app.GestionarButton.Text = 'Gestionar';
            app.AadirButton = uibutton(app.Panel_3, 'push');
            app.AadirButton.ButtonPushedFcn = createCallbackFcn(app, @AnadirBoton_Calculadora, true);
            app.AadirButton.Text = 'Añadir';
            app.ModificarModelosButton = uibutton(app.Panel_3, 'push');
            app.ModificarModelosButton.ButtonPushedFcn = createCallbackFcn(app, @ModificarModelos_Calculadora, true);
            app.ModificarModelosButton.Text = {'Modificar'; 'Modelos'};
            app.Panel_5 = uipanel(app.UIFigure);
            app.Panel_5.Visible = 'off';
            app.Image7 = uiimage(app.Panel_5);
            app.ClculoLabel = uilabel(app.Panel_5);
            app.ClculoLabel.Text = 'Cálculo';
            app.Image7_2 = uiimage(app.Panel_5);
            app.ParmetrosLabel = uilabel(app.Panel_5);
            app.ParmetrosLabel.Text = 'Parámetros';
            app.ConsumomensualLabel = uilabel(app.Panel_5);
            app.ConsumomensualLabel.Text = 'Consumo mensual';
            app.ConsumodiariopromedioLabel = uilabel(app.Panel_5);
            app.ConsumodiariopromedioLabel.Text = 'Consumo diario promedio';
            app.PotenciapicoLabel = uilabel(app.Panel_5);
            app.PotenciapicoLabel.Text = 'Potencia pico';
            app.VarianzadeconsumoLabel = uilabel(app.Panel_5);
            app.VarianzadeconsumoLabel.Text = 'Varianza de consumo';
            app.EditField = uieditfield(app.Panel_5, 'numeric');
            app.EditField_4 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_3 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_2 = uieditfield(app.Panel_5, 'numeric');
            app.Panel_6 = uipanel(app.UIFigure);
            app.Panel_6.Visible = 'off';
            app.Image7_4 = uiimage(app.Panel_6);
            app.ElectrodomsticoLabel = uilabel(app.Panel_6);
            app.ElectrodomsticoLabel.Text = 'Electrodoméstico';
            app.UITable_2 = uitable(app.Panel_6);
            app.UITable_2.ColumnName = {'Nombre'; 'Consumo'; 'Tipo'; ''; ''; ''};
            app.UITable_2.RowName = {};
            app.Button = uibutton(app.Panel_6, 'push');
            app.Button.Text = '';
            app.Image8 = uiimage(app.Panel_6);
            app.Image8.ImageSource = 'Lupa.png';
            app.LimpiarButton_2 = uibutton(app.Panel_6, 'push');
            app.LimpiarButton_2.ButtonPushedFcn = createCallbackFcn(app, @Limpiar_ModificarModelos, true);
            app.LimpiarButton_2.Enable = 'off';
            app.LimpiarButton_2.Text = 'Limpiar';
            app.EliminartodoButton_2 = uibutton(app.Panel_6, 'push');
            app.EliminartodoButton_2.Enable = 'off';
            app.EliminartodoButton_2.Text = 'Eliminar todo';
            app.ModificarP_ActualizarButton = uibutton(app.Panel_6, 'push');
            app.ModificarP_ActualizarButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarModificarModelos, true);
            app.ModificarP_ActualizarButton.Enable = 'off';
            app.ModificarP_ActualizarButton.Text = 'Actualizar';
            app.SeleccioneDropDown = uidropdown(app.Panel_6);
            app.SeleccioneDropDown.Visible = 'off';
            app.SeleccioneDropDown.ClickedFcn = createCallbackFcn(app, @ModificarElectrodomesticoCallback, true);
            app.ConsumoEditFieldLabel = uilabel(app.Panel_6);
            app.ConsumoEditFieldLabel.Text = 'Consumo';
            app.ModificarP_ConsumoEditField = uieditfield(app.Panel_6, 'numeric');
            app.TipoDropDown_2Label = uilabel(app.Panel_6);
            app.TipoDropDown_2Label.Text = 'Tipo';
            app.TipoDropDown_2 = uidropdown(app.Panel_6);
            app.TipoDropDown_2.Items = {'Esencial', 'No esencial', 'Altamente esencial'};
            app.TipoDropDown_2.Value = 'Esencial';
            app.HorasLabel_2 = uilabel(app.Panel_6);
            app.HorasLabel_2.Text = 'Horas';
            app.ModificarP_HorasEditField_2 = uieditfield(app.Panel_6, 'numeric');
            app.DasLabel_2 = uilabel(app.Panel_6);
            app.DasLabel_2.Text = 'Días';
            app.ModificarP_DiasEditField_2 = uieditfield(app.Panel_6, 'numeric');
            app.ModificarButton = uibutton(app.Panel_6, 'push');
            app.ModificarButton.ButtonPushedFcn = createCallbackFcn(app, @ModificarCallback, true);
            app.ModificarButton.Enable = 'off';
            app.ModificarButton.Text = 'Modificar';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Visible = 'off';
            app.UITable = uitable(app.Panel_4);
            app.UITable.ColumnName = {'Nombre'; 'Potencia'; 'Horas'; 'Días'; 'Tipo'};
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.EliminartodoButton = uibutton(app.Panel_4, 'push');
            app.EliminartodoButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarCallback, true);
            app.EliminartodoButton.Enable = 'off';
            app.EliminartodoButton.Text = 'Eliminar todo';
            app.AnadirElectrodomesticoT = uibutton(app.Panel_4, 'push');
            app.AnadirElectrodomesticoT.ButtonPushedFcn = createCallbackFcn(app, @anadirElectrodomesticoGestionar, true);
            app.AnadirElectrodomesticoT.Text = {'Añadir '; 'electrodoméstico'};
            app.CalcularconsumoButton = uibutton(app.Panel_4, 'push');
            app.CalcularconsumoButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularconsumoButtonPushed, true);
            app.CalcularconsumoButton.Text = {'Calcular'; ' consumo'};
            app.Image9 = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end