classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        P_Main                          matlab.ui.Figure
        G_Estadistica                   matlab.ui.container.Panel
        A_Year_Estadistica              matlab.ui.control.DropDown
        RecibosButton                   matlab.ui.control.Button
        ElectrodomesticosButton         matlab.ui.control.Button
        G_Estadistica_Recibo            matlab.ui.control.UIAxes
        P_Botones_Tendencias_1          matlab.ui.container.Panel
        B_TendenciasConsumo_1           matlab.ui.control.Button
        B_TendenciasCostoKWH_1          matlab.ui.control.Button
        B_DatosClimaticos_1             matlab.ui.control.Button
        L_DescripcionRadTemp            matlab.ui.control.Label
        P_Tendencias_1                  matlab.ui.container.Panel
        P_TendenciaCostoMeses_1         matlab.ui.container.Panel
        TA_DescripcionAnalisisCostoMeses_Tendencias_1  matlab.ui.control.TextArea
        G_CostoMeses_Tendencias_1       matlab.ui.control.UIAxes
        P_TendenciaAnalisisRadTemp_1    matlab.ui.container.Panel
        B_Tendencia_CambiarGraf_1       matlab.ui.control.StateButton
        TA_DescripcionAnalisisRadTemp_Tendencias_1  matlab.ui.control.TextArea
        G_RadTemp_Tendencias_2          matlab.ui.control.UIAxes
        G_RadTemp_Tendencias_1          matlab.ui.control.UIAxes
        P_TendenciaProduccion_1         matlab.ui.container.Panel
        TA_DescripcionAnalisisPvsT_Tendencias_1  matlab.ui.control.TextArea
        G_PvsT_Tendencias_1             matlab.ui.control.UIAxes
        B_EditarRec_Tendencias_1        matlab.ui.control.Button
        B_AnadirRec_Tendencias_1        matlab.ui.control.Button
        B_EditarE_Tendencia_1           matlab.ui.control.Button
        B_AnadirE_Tendencias_1          matlab.ui.control.Button
        AnlisisdeDatosLabel             matlab.ui.control.Label
        P_Botones_Recibo_1              matlab.ui.container.Panel
        B_ReciboAnadir_1                matlab.ui.control.Button
        B_ReciboGestionar_1             matlab.ui.control.Button
        P_TablaRecibo_Consumo_1         matlab.ui.container.Panel
        D_Fecha_Recibo                  matlab.ui.control.DatePicker
        FechaReciboDatePickerLabel      matlab.ui.control.Label
        Fi_ReciboCostoTotal_1           matlab.ui.control.NumericEditField
        CostoTotalEditField_2Label      matlab.ui.control.Label
        Fi_ReciboConsumo_1              matlab.ui.control.NumericEditField
        ConsumomensualEditFieldLabel    matlab.ui.control.Label
        I_Recibo                        matlab.ui.control.Image
        L_Recibo                        matlab.ui.control.Label
        B_ActualizarRecibo_1            matlab.ui.control.Button
        B_EliminarRecibo_1              matlab.ui.control.Button
        B_EditarRecibo_1                matlab.ui.control.Button
        T_Recibo_1                      matlab.ui.control.Table
        P_IngresarRecibo_1              matlab.ui.container.Panel
        Fi_ConsumoMesA_IngresarR_1      matlab.ui.control.DatePicker
        FechaReciboLabel                matlab.ui.control.Label
        Fi_CostoTotal_IngresarR_1       matlab.ui.control.NumericEditField
        CostoTotalEditFieldLabel        matlab.ui.control.Label
        Fi_ConsumoMensual_IngresarR_1   matlab.ui.control.NumericEditField
        ConsumoMensualEditFieldLabel    matlab.ui.control.Label
        IngreselosdatosdesureciboLabel  matlab.ui.control.Label
        B_Ingresar_IngresarRecibo_1     matlab.ui.control.Button
        B_Regresar_IngresarRecibo_1     matlab.ui.control.Button
        P_inicio_1                      matlab.ui.container.Panel
        B_Tendencias_1                  matlab.ui.control.Button
        B_Ingresar_Electrodomestcos_1   matlab.ui.control.Button
        B_Ingresar_Recibos_1            matlab.ui.control.Button
        ResultadoLabel                  matlab.ui.control.Label
        P_Botones_CalculadoraConsumo_1  matlab.ui.container.Panel
        B_ModificarElectrodomesticos_IngresarE_1  matlab.ui.control.Button
        B_Anadir_IngresarE_1            matlab.ui.control.Button
        B_CalcularConsumo_IngresarE_1   matlab.ui.control.Button
        P_Anadir_IngresarE_1            matlab.ui.container.Panel
        Image9                          matlab.ui.control.Image
        UnidadesLabel_3                 matlab.ui.control.Label
        Fi_Cantidad_IngresarE_1         matlab.ui.control.NumericEditField
        CantidadEditFieldLabel          matlab.ui.control.Label
        B_Regresar_IngresarE_1          matlab.ui.control.Button
        D_Ambiente_IngresarE_1          matlab.ui.control.DropDown
        AmbienteDropDownLabel           matlab.ui.control.Label
        HorasLabel_1                    matlab.ui.control.Label
        Fi_HorasNoche_1_IngresarE_1     matlab.ui.control.NumericEditField
        UsopordaLabel_2                 matlab.ui.control.Label
        DiasLabel_2                     matlab.ui.control.Label
        UsoporsemanaLabel_2             matlab.ui.control.Label
        Fi_Uso_Semana_IngresarE_1       matlab.ui.control.NumericEditField
        D_Electrodomesticos_IngresarE_1  matlab.ui.control.ListBox
        HorasLabel_2                    matlab.ui.control.Label
        WattsLabel                      matlab.ui.control.Label
        Fi_HorasDias_IngresarE_1        matlab.ui.control.NumericEditField
        UsopordaLabel                   matlab.ui.control.Label
        Fi_PotenciaEnWatts_IngresarE_1  matlab.ui.control.NumericEditField
        PotenciaLabel                   matlab.ui.control.Label
        Fi_Nombre_anadir_IngresarE_1    matlab.ui.control.EditField
        NombreEditFieldLabel            matlab.ui.control.Label
        D_Tipo_anadir_IngresarE_1       matlab.ui.control.DropDown
        TipoDropDown_2Label             matlab.ui.control.Label
        B_Agregar_anadir_IngresarE_1    matlab.ui.control.Button
        B_CalcularPotencia_anadir_IngresarE_1  matlab.ui.control.Button
        P_CalcularConsumo_IngresarE_1   matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ConsumoMensualTab               matlab.ui.container.Tab
        Ln_Potencia_Pico                matlab.ui.control.NumericEditField
        PotenciaPicoEditFieldLabel      matlab.ui.control.Label
        Ln_Consumo_Mensual_Promedio     matlab.ui.control.NumericEditField
        ConsumoMensualPromedioEditFieldLabel  matlab.ui.control.Label
        Ln_Consumo_Mensual              matlab.ui.control.NumericEditField
        ConsumoMensualEditFieldLabel_2  matlab.ui.control.Label
        ConsumoRelativoTab              matlab.ui.container.Tab
        Ln_Potencia_Pico_Relativa       matlab.ui.control.NumericEditField
        PotenciaPicoRelativaEditFieldLabel  matlab.ui.control.Label
        Ln_Consumo_Relativo_Promedio    matlab.ui.control.NumericEditField
        ConsumoRelativoPromedioLabel    matlab.ui.control.Label
        Ln_Consumo_Relativo             matlab.ui.control.NumericEditField
        ConsumoRelativoEditFieldLabel   matlab.ui.control.Label
        P_ModificarElectrodomesticos_IngresarE_1  matlab.ui.container.Panel
        Fi_Unidad_Electrodomestico      matlab.ui.control.NumericEditField
        UEditFieldLabel                 matlab.ui.control.Label
        B_Eliminar_Electrodomestico     matlab.ui.control.Button
        B_Actualizar_Electrodomestico   matlab.ui.control.Button
        B_Editar_Electrodomestico       matlab.ui.control.Button
        Fi_Horas_ModificarE_2           matlab.ui.control.NumericEditField
        HorasLabel_3                    matlab.ui.control.Label
        NombreLabel                     matlab.ui.control.Label
        T_Electrodomesticos_Gestionar_1  matlab.ui.control.Table
        Fi_Dias_ModificarE_1            matlab.ui.control.NumericEditField
        DasLabel                        matlab.ui.control.Label
        Fi_Horas_ModificarE_1           matlab.ui.control.NumericEditField
        HorasLabel                      matlab.ui.control.Label
        D_Tipo_ModificarE_1             matlab.ui.control.DropDown
        TipoDropDownLabel               matlab.ui.control.Label
        Fi_Modificar_ModificarE_1       matlab.ui.control.NumericEditField
        PotenciaLabel_2                 matlab.ui.control.Label
        B_LimpiarCampos_ModificarE_1    matlab.ui.control.Button
        Image8                          matlab.ui.control.Image
        B_BuscarElectrodomestico_ModificarE_1  matlab.ui.control.Button
        ElectrodomsticoLabel            matlab.ui.control.Label
        Image7                          matlab.ui.control.Image
        P_Botones_Inicio                matlab.ui.container.Panel
        Container_Botones               matlab.ui.container.GridLayout
        B_EnergiaProducida_3            matlab.ui.control.Button
        B_Instalacion_2                 matlab.ui.control.Button
        B_CalculadoraConsumo_1          matlab.ui.control.Button
        CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel  matlab.ui.control.Label
    end

            properties (Access = public)
                Usuario
                M1
            end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('Ejecutando startupFcn...');
            app.Usuario = C_Usuario(1, getenv('USERNAME'), "Arequipa");
            disp("hola usuario");
            app.M1 = M1_Calculadora_Consumo(app.Usuario);
            disp("hola m1");
        end

        % Button pushed function: B_CalculadoraConsumo_1
        function CalculadoraConsumoView_1(app, event)
            app.G_Estadistica.Visible = "off";
            FC_showView(app,"1")
        end

        % Button pushed function: B_Ingresar_Recibos_1
        function IngresarRecibosView_1(app, event)
            FC_showView(app,"1.1")
        end

        % Button pushed function: B_Regresar_IngresarRecibo_1
        function RegresarReciboElectrodomesticoview(app, event)
            FC_showView(app,"1.1.2")
        end

        % Button pushed function: B_ReciboAnadir_1
        function ReciboAnadirView(app, event)
            app.G_Estadistica.Visible = "off";
            FC_showView(app,"1.1.3")
        end

        % Button pushed function: B_ReciboGestionar_1
        function ReciboGestionarView(app, event)
            app.G_Estadistica.Visible = "off";
            FC_showView(app,"1.1.4")
            Tabla_Recibos(app);
        end

        % Button pushed function: B_Ingresar_Electrodomestcos_1
        function IngresarElectrodomesticosView(app, event)
            FC_showView(app,"1.2.1")
            Button_Ingresar_Electrodomesticos(app);
        end

        % Button pushed function: B_Regresar_IngresarE_1
        function RegresarAnadirElectrodomesticoView(app, event)
            FC_showView(app,"1.1.2")
        end

        % Button pushed function: B_ModificarElectrodomesticos_IngresarE_1
        function ModificarE_IngresarE_1(app, event)
            FC_showView(app,"1.2.4")
            app.T_Electrodomesticos_Gestionar_1.Data = app.M1.Table_Electrodomesticos();
        end

        % Callback function
        function GestionarE_IngresarE_1_View(app, event)
            FC_showView(app,"1.2.2");
            GestionarE_IngresarE_1_View_Function(app)
        end

        % Button pushed function: B_Anadir_IngresarE_1
        function AnadirE_IngresarE_1_View(app, event)
            FC_showView(app,"1.2.1")
        end

        % Button pushed function: B_CalcularConsumo_IngresarE_1
        function CalcularConsumoE_IngresarE_1_View(app, event)
            FC_showView(app,"1.2.3")
            Calcular_Consumo(app);
        end

        % Value changed function: D_Ambiente_IngresarE_1
        function D_Ambiente_IngresarE_1ValueChanged(app, event)
            Seleccion_Ambiente(app)
        end

        % Clicked callback: D_Electrodomesticos_IngresarE_1
        function D_Electrodomesticos_IngresarE_1Clicked(app, event)
            Seleccion_Electrodomestico(app);
        end

        % Button pushed function: B_Agregar_anadir_IngresarE_1
        function B_Agregar_anadir_IngresarE_1ButtonPushed(app, event)
            Agregar_Electrodomestico(app);
            app.T_Electrodomesticos_Gestionar_1.Data = app.M1.Table_Electrodomesticos();
        end

        % Callback function
        function B_CalcularConsumoe_Gestionar_1ButtonPushed(app, event)
            FC_showView(app,"1.2.3")
            Calcular_Consumo(app)
        end

        % Button pushed function: B_Ingresar_IngresarRecibo_1
        function B_Ingresar_IngresarRecibo_1ButtonPushed(app, event)
            Ingresar_Recibo(app);
        end

        % Selection changed function: T_Recibo_1
        function T_Recibo_1SelectionChanged(app, event)
            Seleccion_Recibo_Tabla(app);
        end

        % Callback function: A_Year_Estadistica, I_Recibo
        function I_ReciboImageClicked(app, event)
            Grafica_Recibos(app)
        end

        % Callback function
        function anadirE_IngresarE_1_View2(app, event)
            FC_showView(app,"1.2.1")
        end

        % Button pushed function: B_AnadirE_Tendencias_1
        function B_Tendencias_AnadirE_View(app, event)
            FC_showView(app,"1.2.1")
        end

        % Button pushed function: B_EditarE_Tendencia_1
        function B_Tendencias_ModificarE_View(app, event)
            FC_showView(app,"1.2.5")
            %Por ahora lleva a gestionar Electrodomesticos
        end

        % Button pushed function: B_AnadirRec_Tendencias_1
        function B_Tendencias_AnadirRec_View(app, event)
            FC_showView(app,"1.1.3")
        end

        % Button pushed function: B_EditarRec_Tendencias_1
        function B_Tendencias_ModificarRec_View(app, event)
            FC_showView(app,"1.1.4")
        end

        % Button pushed function: B_Tendencias_1
        function B_Tendencias_View(app, event)
            FC_showView(app,"1.3.1")
        end

        % Button pushed function: B_TendenciasCostoKWH_1
        function B_Tendencias_Consumo_View(app, event)
            FC_showView(app,"1.3.2")
                GraficoConsumoTendencia(app)
        end

        % Button pushed function: B_DatosClimaticos_1
        function B_Tendencias_CostoKWH_View(app, event)
            FC_showView(app,"1.3.3")
            GraficoCostoKWH(app)
        end

        % Button pushed function: B_TendenciasConsumo_1
        function B_Tendencias_DatosClimaticos_View(app, event)
            FC_showView(app,"1.3.4")
            plotRadiacionTemperatura(app)
        end

        % Button pushed function: B_EditarRecibo_1
        function B_EditarRecibo_1ButtonPushed(app, event)
            app.Fi_ReciboCostoTotal_1.Editable ="on";
            app.Fi_ReciboConsumo_1.Editable = "on";
            app.B_EditarRecibo_1.Visible = "off";
            app.B_EliminarRecibo_1.Visible = "on";
            app.B_ActualizarRecibo_1.Visible = "on";
        end

        % Cell selection callback: T_Electrodomesticos_Gestionar_1
        function MostrarDatos_1Fila(app, event)
            Seleccion_Electrodomestico_Tabla(app);
        end

        % Button pushed function: B_ActualizarRecibo_1
        function B_ActualizarRecibo_1ButtonPushed(app, event)
            Update_Recibo(app);
        end

        % Button pushed function: B_Actualizar_Electrodomestico
        function B_Actualizar_ElectrodomesticoButtonPushed(app, event)
            Update_Electrodomestico(app);
        end

        % Button pushed function: B_Editar_Electrodomestico
        function B_Editar_ElectrodomesticoButtonPushed(app, event)
            %XD
            app.B_Editar_Electrodomestico.Visible = "off";
            app.B_Actualizar_Electrodomestico.Visible = "on";
            app.B_Eliminar_Electrodomestico.Visible = "on";
            app.Fi_Modificar_ModificarE_1.Editable = "on";
            app.Fi_Dias_ModificarE_1.Editable = "on";
            app.Fi_Horas_ModificarE_1.Editable = "on";
            app.Fi_Horas_ModificarE_2.Editable = "on";
            app.Fi_Unidad_Electrodomestico.Editable = "on";
        end

        % Button pushed function: B_EliminarRecibo_1
        function B_EliminarRecibo_1ButtonPushed(app, event)
            Eliminar_Recibo(app);
        end

        % Button pushed function: B_Eliminar_Electrodomestico
        function B_Eliminar_ElectrodomesticoButtonPushed(app, event)
            Eliminar_Electrodomestico(app);
        end

        % Close request function: P_Main
        function P_MainCloseRequest(app, event)
            Carga_BD(app);
            delete(app)
        end

        % Value changed function: B_Tendencia_CambiarGraf_1
        function B_Tendencia_CambiarGraf_1ValueChanged(app, event)
            value = app.B_Tendencia_CambiarGraf_1.Value;
            switchGraphVisibility(app, app.G_RadTemp_Tendencias_1, app.G_RadTemp_Tendencias_2, value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.P_Main = uifigure('Visible', 'off');
            app.P_Main.Name = 'MATLAB App';
            app.P_Main.CloseRequestFcn = createCallbackFcn(app, @P_MainCloseRequest, true);
            app.CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel = uilabel(app.P_Main);
            app.CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel.Text = 'Calculadora de ROI en la implementación de energía solar vs eléctrica';
            app.P_Botones_Inicio = uipanel(app.P_Main);
            app.Container_Botones = uigridlayout(app.P_Botones_Inicio);
            app.B_CalculadoraConsumo_1 = uibutton(app.Container_Botones, 'push');
            app.B_CalculadoraConsumo_1.ButtonPushedFcn = createCallbackFcn(app, @CalculadoraConsumoView_1, true);
            app.B_CalculadoraConsumo_1.Text = 'Calculadora de Consumo';
            app.B_Instalacion_2 = uibutton(app.Container_Botones, 'push');
            app.B_Instalacion_2.Icon = 'panel.png';
            app.B_Instalacion_2.Text = 'Instalación';
            app.B_EnergiaProducida_3 = uibutton(app.Container_Botones, 'push');
            app.B_EnergiaProducida_3.Text = 'Energía Producida';
            app.P_ModificarElectrodomesticos_IngresarE_1 = uipanel(app.P_Main);
            app.P_ModificarElectrodomesticos_IngresarE_1.Visible = 'off';
            app.Image7 = uiimage(app.P_ModificarElectrodomesticos_IngresarE_1);
            app.ElectrodomsticoLabel = uilabel(app.P_ModificarElectrodomesticos_IngresarE_1);
            app.ElectrodomsticoLabel.Text = 'Electrodoméstico';
            app.B_BuscarElectrodomestico_ModificarE_1 = uibutton(app.P_ModificarElectrodomesticos_IngresarE_1, 'push');
            app.B_BuscarElectrodomestico_ModificarE_1.Text = '';
            app.Image8 = uiimage(app.P_ModificarElectrodomesticos_IngresarE_1);
            app.Image8.ImageSource = 'Lupa.png';
            app.B_LimpiarCampos_ModificarE_1 = uibutton(app.P_ModificarElectrodomesticos_IngresarE_1, 'push');
            app.B_LimpiarCampos_ModificarE_1.Enable = 'off';
            app.B_LimpiarCampos_ModificarE_1.Visible = 'off';
            app.B_LimpiarCampos_ModificarE_1.Text = 'Limpiar';
            app.PotenciaLabel_2 = uilabel(app.P_ModificarElectrodomesticos_IngresarE_1);
            app.PotenciaLabel_2.Text = 'Potencia';
            app.Fi_Modificar_ModificarE_1 = uieditfield(app.P_ModificarElectrodomesticos_IngresarE_1, 'numeric');
            app.Fi_Modificar_ModificarE_1.Editable = 'off';
            app.TipoDropDownLabel = uilabel(app.P_ModificarElectrodomesticos_IngresarE_1);
            app.TipoDropDownLabel.Text = 'Tipo';
            app.D_Tipo_ModificarE_1 = uidropdown(app.P_ModificarElectrodomesticos_IngresarE_1);
            app.D_Tipo_ModificarE_1.Items = {'Esencial', 'No Esencial', 'Altamente Esencial'};
            app.D_Tipo_ModificarE_1.Value = 'Esencial';
            app.HorasLabel = uilabel(app.P_ModificarElectrodomesticos_IngresarE_1);
            app.HorasLabel.Text = 'Horas D.';
            app.Fi_Horas_ModificarE_1 = uieditfield(app.P_ModificarElectrodomesticos_IngresarE_1, 'numeric');
            app.Fi_Horas_ModificarE_1.Editable = 'off';
            app.DasLabel = uilabel(app.P_ModificarElectrodomesticos_IngresarE_1);
            app.DasLabel.Text = 'Días';
            app.Fi_Dias_ModificarE_1 = uieditfield(app.P_ModificarElectrodomesticos_IngresarE_1, 'numeric');
            app.Fi_Dias_ModificarE_1.Editable = 'off';
            app.T_Electrodomesticos_Gestionar_1 = uitable(app.P_ModificarElectrodomesticos_IngresarE_1);
            app.T_Electrodomesticos_Gestionar_1.ColumnName = {'codigo'; 'nombre'; 'Potencia'; 'Horas Día'; 'Horas noche'; 'Días'; 'Cantidad'; 'Tipo'};
            app.T_Electrodomesticos_Gestionar_1.RowName = {};
            app.T_Electrodomesticos_Gestionar_1.CellSelectionCallback = createCallbackFcn(app, @MostrarDatos_1Fila, true);
            app.NombreLabel = uilabel(app.P_ModificarElectrodomesticos_IngresarE_1);
            app.NombreLabel.Interpreter = 'tex';
            app.NombreLabel.Text = 'Nombre';
            app.HorasLabel_3 = uilabel(app.P_ModificarElectrodomesticos_IngresarE_1);
            app.HorasLabel_3.Text = 'Horas N.';
            app.Fi_Horas_ModificarE_2 = uieditfield(app.P_ModificarElectrodomesticos_IngresarE_1, 'numeric');
            app.Fi_Horas_ModificarE_2.Editable = 'off';
            app.B_Editar_Electrodomestico = uibutton(app.P_ModificarElectrodomesticos_IngresarE_1, 'push');
            app.B_Editar_Electrodomestico.ButtonPushedFcn = createCallbackFcn(app, @B_Editar_ElectrodomesticoButtonPushed, true);
            app.B_Editar_Electrodomestico.Visible = 'off';
            app.B_Editar_Electrodomestico.Text = 'Editar';
            app.B_Actualizar_Electrodomestico = uibutton(app.P_ModificarElectrodomesticos_IngresarE_1, 'push');
            app.B_Actualizar_Electrodomestico.ButtonPushedFcn = createCallbackFcn(app, @B_Actualizar_ElectrodomesticoButtonPushed, true);
            app.B_Actualizar_Electrodomestico.Visible = 'off';
            app.B_Actualizar_Electrodomestico.Text = 'Actualizar';
            app.B_Eliminar_Electrodomestico = uibutton(app.P_ModificarElectrodomesticos_IngresarE_1, 'push');
            app.B_Eliminar_Electrodomestico.ButtonPushedFcn = createCallbackFcn(app, @B_Eliminar_ElectrodomesticoButtonPushed, true);
            app.B_Eliminar_Electrodomestico.Visible = 'off';
            app.B_Eliminar_Electrodomestico.Text = 'Eliminar';
            app.UEditFieldLabel = uilabel(app.P_ModificarElectrodomesticos_IngresarE_1);
            app.UEditFieldLabel.Text = 'U';
            app.Fi_Unidad_Electrodomestico = uieditfield(app.P_ModificarElectrodomesticos_IngresarE_1, 'numeric');
            app.Fi_Unidad_Electrodomestico.Editable = 'off';
            app.P_CalcularConsumo_IngresarE_1 = uipanel(app.P_Main);
            app.P_CalcularConsumo_IngresarE_1.Visible = 'off';
            app.TabGroup = uitabgroup(app.P_CalcularConsumo_IngresarE_1);
            app.ConsumoMensualTab = uitab(app.TabGroup);
            app.ConsumoMensualTab.Title = 'Consumo Mensual';
            app.ConsumoMensualEditFieldLabel_2 = uilabel(app.ConsumoMensualTab);
            app.ConsumoMensualEditFieldLabel_2.Text = 'Consumo Mensual';
            app.Ln_Consumo_Mensual = uieditfield(app.ConsumoMensualTab, 'numeric');
            app.Ln_Consumo_Mensual.ValueDisplayFormat = '%.2f';
            app.Ln_Consumo_Mensual.Editable = 'off';
            app.ConsumoMensualPromedioEditFieldLabel = uilabel(app.ConsumoMensualTab);
            app.ConsumoMensualPromedioEditFieldLabel.Text = {'Consumo Mensual'; 'Promedio'};
            app.Ln_Consumo_Mensual_Promedio = uieditfield(app.ConsumoMensualTab, 'numeric');
            app.Ln_Consumo_Mensual_Promedio.Editable = 'off';
            app.PotenciaPicoEditFieldLabel = uilabel(app.ConsumoMensualTab);
            app.PotenciaPicoEditFieldLabel.Text = 'Potencia Pico';
            app.Ln_Potencia_Pico = uieditfield(app.ConsumoMensualTab, 'numeric');
            app.Ln_Potencia_Pico.Editable = 'off';
            app.ConsumoRelativoTab = uitab(app.TabGroup);
            app.ConsumoRelativoTab.Title = 'Consumo Relativo';
            app.ConsumoRelativoEditFieldLabel = uilabel(app.ConsumoRelativoTab);
            app.ConsumoRelativoEditFieldLabel.Text = 'Consumo Relativo';
            app.Ln_Consumo_Relativo = uieditfield(app.ConsumoRelativoTab, 'numeric');
            app.Ln_Consumo_Relativo.ValueDisplayFormat = '%.2f';
            app.Ln_Consumo_Relativo.Editable = 'off';
            app.ConsumoRelativoPromedioLabel = uilabel(app.ConsumoRelativoTab);
            app.ConsumoRelativoPromedioLabel.Text = 'Consumo Relativo Promedio';
            app.Ln_Consumo_Relativo_Promedio = uieditfield(app.ConsumoRelativoTab, 'numeric');
            app.Ln_Consumo_Relativo_Promedio.Editable = 'off';
            app.PotenciaPicoRelativaEditFieldLabel = uilabel(app.ConsumoRelativoTab);
            app.PotenciaPicoRelativaEditFieldLabel.Text = 'Potencia Pico Relativa';
            app.Ln_Potencia_Pico_Relativa = uieditfield(app.ConsumoRelativoTab, 'numeric');
            app.Ln_Potencia_Pico_Relativa.Editable = 'off';
            app.Label = uilabel(app.P_CalcularConsumo_IngresarE_1);
            app.Label.Text = 'Se esta tomando en considecación un Panel de 550W a 9 horas de un funcionamiento del 100%';
            app.P_Anadir_IngresarE_1 = uipanel(app.P_Main);
            app.P_Anadir_IngresarE_1.Visible = 'off';
            app.B_CalcularPotencia_anadir_IngresarE_1 = uibutton(app.P_Anadir_IngresarE_1, 'push');
            app.B_CalcularPotencia_anadir_IngresarE_1.Enable = 'off';
            app.B_CalcularPotencia_anadir_IngresarE_1.Text = 'Calcular Potencia';
            app.B_Agregar_anadir_IngresarE_1 = uibutton(app.P_Anadir_IngresarE_1, 'push');
            app.B_Agregar_anadir_IngresarE_1.ButtonPushedFcn = createCallbackFcn(app, @B_Agregar_anadir_IngresarE_1ButtonPushed, true);
            app.B_Agregar_anadir_IngresarE_1.Text = 'Agregar';
            app.TipoDropDown_2Label = uilabel(app.P_Anadir_IngresarE_1);
            app.TipoDropDown_2Label.Text = 'Tipo';
            app.D_Tipo_anadir_IngresarE_1 = uidropdown(app.P_Anadir_IngresarE_1);
            app.D_Tipo_anadir_IngresarE_1.Items = {};
            app.D_Tipo_anadir_IngresarE_1.Placeholder = 'Tipo Esencial';
            app.D_Tipo_anadir_IngresarE_1.Value = {};
            app.NombreEditFieldLabel = uilabel(app.P_Anadir_IngresarE_1);
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.Fi_Nombre_anadir_IngresarE_1 = uieditfield(app.P_Anadir_IngresarE_1, 'text');
            app.Fi_Nombre_anadir_IngresarE_1.Editable = 'off';
            app.Fi_Nombre_anadir_IngresarE_1.Placeholder = 'Electrodomesticos';
            app.PotenciaLabel = uilabel(app.P_Anadir_IngresarE_1);
            app.PotenciaLabel.Text = 'Potencia';
            app.Fi_PotenciaEnWatts_IngresarE_1 = uieditfield(app.P_Anadir_IngresarE_1, 'numeric');
            app.UsopordaLabel = uilabel(app.P_Anadir_IngresarE_1);
            app.UsopordaLabel.Text = 'Uso de día';
            app.Fi_HorasDias_IngresarE_1 = uieditfield(app.P_Anadir_IngresarE_1, 'numeric');
            app.WattsLabel = uilabel(app.P_Anadir_IngresarE_1);
            app.WattsLabel.Text = 'Watts';
            app.HorasLabel_2 = uilabel(app.P_Anadir_IngresarE_1);
            app.HorasLabel_2.Text = 'Horas';
            app.D_Electrodomesticos_IngresarE_1 = uilistbox(app.P_Anadir_IngresarE_1);
            app.D_Electrodomesticos_IngresarE_1.Items = {};
            app.D_Electrodomesticos_IngresarE_1.Tooltip = {''};
            app.D_Electrodomesticos_IngresarE_1.ClickedFcn = createCallbackFcn(app, @D_Electrodomesticos_IngresarE_1Clicked, true);
            app.D_Electrodomesticos_IngresarE_1.Value = {};
            app.Fi_Uso_Semana_IngresarE_1 = uieditfield(app.P_Anadir_IngresarE_1, 'numeric');
            app.UsoporsemanaLabel_2 = uilabel(app.P_Anadir_IngresarE_1);
            app.UsoporsemanaLabel_2.Text = 'Uso por semana';
            app.DiasLabel_2 = uilabel(app.P_Anadir_IngresarE_1);
            app.DiasLabel_2.Text = 'Días';
            app.UsopordaLabel_2 = uilabel(app.P_Anadir_IngresarE_1);
            app.UsopordaLabel_2.Text = 'Uso de noche';
            app.Fi_HorasNoche_1_IngresarE_1 = uieditfield(app.P_Anadir_IngresarE_1, 'numeric');
            app.HorasLabel_1 = uilabel(app.P_Anadir_IngresarE_1);
            app.HorasLabel_1.Text = 'Horas';
            app.AmbienteDropDownLabel = uilabel(app.P_Anadir_IngresarE_1);
            app.AmbienteDropDownLabel.Text = 'Ambiente';
            app.D_Ambiente_IngresarE_1 = uidropdown(app.P_Anadir_IngresarE_1);
            app.D_Ambiente_IngresarE_1.Items = {};
            app.D_Ambiente_IngresarE_1.ValueChangedFcn = createCallbackFcn(app, @D_Ambiente_IngresarE_1ValueChanged, true);
            app.D_Ambiente_IngresarE_1.Placeholder = 'Seleccione';
            app.D_Ambiente_IngresarE_1.Value = {};
            app.B_Regresar_IngresarE_1 = uibutton(app.P_Anadir_IngresarE_1, 'push');
            app.B_Regresar_IngresarE_1.ButtonPushedFcn = createCallbackFcn(app, @RegresarAnadirElectrodomesticoView, true);
            app.B_Regresar_IngresarE_1.Text = 'Regresar';
            app.CantidadEditFieldLabel = uilabel(app.P_Anadir_IngresarE_1);
            app.CantidadEditFieldLabel.Text = 'Cantidad';
            app.Fi_Cantidad_IngresarE_1 = uieditfield(app.P_Anadir_IngresarE_1, 'numeric');
            app.UnidadesLabel_3 = uilabel(app.P_Anadir_IngresarE_1);
            app.UnidadesLabel_3.Text = 'Ud.';
            app.Image9 = uiimage(app.P_Anadir_IngresarE_1);
            app.P_Botones_CalculadoraConsumo_1 = uipanel(app.P_Main);
            app.P_Botones_CalculadoraConsumo_1.Visible = 'off';
            app.B_CalcularConsumo_IngresarE_1 = uibutton(app.P_Botones_CalculadoraConsumo_1, 'push');
            app.B_CalcularConsumo_IngresarE_1.ButtonPushedFcn = createCallbackFcn(app, @CalcularConsumoE_IngresarE_1_View, true);
            app.B_CalcularConsumo_IngresarE_1.Text = {'Calcular '; 'Consumo'};
            app.B_Anadir_IngresarE_1 = uibutton(app.P_Botones_CalculadoraConsumo_1, 'push');
            app.B_Anadir_IngresarE_1.ButtonPushedFcn = createCallbackFcn(app, @AnadirE_IngresarE_1_View, true);
            app.B_Anadir_IngresarE_1.Text = 'Añadir';
            app.B_ModificarElectrodomesticos_IngresarE_1 = uibutton(app.P_Botones_CalculadoraConsumo_1, 'push');
            app.B_ModificarElectrodomesticos_IngresarE_1.ButtonPushedFcn = createCallbackFcn(app, @ModificarE_IngresarE_1, true);
            app.B_ModificarElectrodomesticos_IngresarE_1.Text = {'Modificar'; 'Electrodomésticos'};
            app.ResultadoLabel = uilabel(app.P_Main);
            app.ResultadoLabel.Visible = 'off';
            app.ResultadoLabel.Text = 'Resultado';
            app.P_inicio_1 = uipanel(app.P_Main);
            app.P_inicio_1.Visible = 'off';
            app.B_Ingresar_Recibos_1 = uibutton(app.P_inicio_1, 'push');
            app.B_Ingresar_Recibos_1.ButtonPushedFcn = createCallbackFcn(app, @IngresarRecibosView_1, true);
            app.B_Ingresar_Recibos_1.Text = 'Ingresar recibos';
            app.B_Ingresar_Electrodomestcos_1 = uibutton(app.P_inicio_1, 'push');
            app.B_Ingresar_Electrodomestcos_1.ButtonPushedFcn = createCallbackFcn(app, @IngresarElectrodomesticosView, true);
            app.B_Ingresar_Electrodomestcos_1.Text = 'Ingresar electrodomésticos';
            app.B_Tendencias_1 = uibutton(app.P_inicio_1, 'push');
            app.B_Tendencias_1.ButtonPushedFcn = createCallbackFcn(app, @B_Tendencias_View, true);
            app.B_Tendencias_1.Text = 'Tendencias';
            app.P_IngresarRecibo_1 = uipanel(app.P_Main);
            app.P_IngresarRecibo_1.Visible = 'off';
            app.B_Regresar_IngresarRecibo_1 = uibutton(app.P_IngresarRecibo_1, 'push');
            app.B_Regresar_IngresarRecibo_1.ButtonPushedFcn = createCallbackFcn(app, @RegresarReciboElectrodomesticoview, true);
            app.B_Regresar_IngresarRecibo_1.Text = 'Regresar';
            app.B_Ingresar_IngresarRecibo_1 = uibutton(app.P_IngresarRecibo_1, 'push');
            app.B_Ingresar_IngresarRecibo_1.ButtonPushedFcn = createCallbackFcn(app, @B_Ingresar_IngresarRecibo_1ButtonPushed, true);
            app.B_Ingresar_IngresarRecibo_1.Text = 'Ingresar';
            app.IngreselosdatosdesureciboLabel = uilabel(app.P_IngresarRecibo_1);
            app.IngreselosdatosdesureciboLabel.Text = 'Ingrese los datos de su recibo';
            app.ConsumoMensualEditFieldLabel = uilabel(app.P_IngresarRecibo_1);
            app.ConsumoMensualEditFieldLabel.Text = 'Consumo Mensual';
            app.Fi_ConsumoMensual_IngresarR_1 = uieditfield(app.P_IngresarRecibo_1, 'numeric');
            app.CostoTotalEditFieldLabel = uilabel(app.P_IngresarRecibo_1);
            app.CostoTotalEditFieldLabel.Text = 'Costo Total';
            app.Fi_CostoTotal_IngresarR_1 = uieditfield(app.P_IngresarRecibo_1, 'numeric');
            app.FechaReciboLabel = uilabel(app.P_IngresarRecibo_1);
            app.FechaReciboLabel.Text = 'Fecha Recibo';
            app.Fi_ConsumoMesA_IngresarR_1 = uidatepicker(app.P_IngresarRecibo_1);
            app.Fi_ConsumoMesA_IngresarR_1.DisplayFormat = 'MM.uuuu';
            app.P_TablaRecibo_Consumo_1 = uipanel(app.P_Main);
            app.P_TablaRecibo_Consumo_1.Visible = 'off';
            app.T_Recibo_1 = uitable(app.P_TablaRecibo_Consumo_1);
            app.T_Recibo_1.ColumnName = {'Numero Recibo'; 'Consumo Mensual'; 'Costo total'; 'Año'; 'Mes'; 'Estado'};
            app.T_Recibo_1.RowName = {};
            app.T_Recibo_1.SelectionChangedFcn = createCallbackFcn(app, @T_Recibo_1SelectionChanged, true);
            app.B_EditarRecibo_1 = uibutton(app.P_TablaRecibo_Consumo_1, 'push');
            app.B_EditarRecibo_1.ButtonPushedFcn = createCallbackFcn(app, @B_EditarRecibo_1ButtonPushed, true);
            app.B_EditarRecibo_1.Visible = 'off';
            app.B_EditarRecibo_1.Text = 'Editar';
            app.B_EliminarRecibo_1 = uibutton(app.P_TablaRecibo_Consumo_1, 'push');
            app.B_EliminarRecibo_1.ButtonPushedFcn = createCallbackFcn(app, @B_EliminarRecibo_1ButtonPushed, true);
            app.B_EliminarRecibo_1.Visible = 'off';
            app.B_EliminarRecibo_1.Text = 'Eliminar';
            app.B_ActualizarRecibo_1 = uibutton(app.P_TablaRecibo_Consumo_1, 'push');
            app.B_ActualizarRecibo_1.ButtonPushedFcn = createCallbackFcn(app, @B_ActualizarRecibo_1ButtonPushed, true);
            app.B_ActualizarRecibo_1.Visible = 'off';
            app.B_ActualizarRecibo_1.Text = 'Actualizar';
            app.L_Recibo = uilabel(app.P_TablaRecibo_Consumo_1);
            app.L_Recibo.Text = 'Recibo';
            app.I_Recibo = uiimage(app.P_TablaRecibo_Consumo_1);
            app.I_Recibo.ImageClickedFcn = createCallbackFcn(app, @I_ReciboImageClicked, true);
            app.I_Recibo.ImageSource = 'Estadistica.png';
            app.ConsumomensualEditFieldLabel = uilabel(app.P_TablaRecibo_Consumo_1);
            app.ConsumomensualEditFieldLabel.Text = 'Consumo mensual';
            app.Fi_ReciboConsumo_1 = uieditfield(app.P_TablaRecibo_Consumo_1, 'numeric');
            app.Fi_ReciboConsumo_1.Editable = 'off';
            app.CostoTotalEditField_2Label = uilabel(app.P_TablaRecibo_Consumo_1);
            app.CostoTotalEditField_2Label.Text = 'Costo Total';
            app.Fi_ReciboCostoTotal_1 = uieditfield(app.P_TablaRecibo_Consumo_1, 'numeric');
            app.Fi_ReciboCostoTotal_1.Editable = 'off';
            app.FechaReciboDatePickerLabel = uilabel(app.P_TablaRecibo_Consumo_1);
            app.FechaReciboDatePickerLabel.Text = 'Fecha-Recibo';
            app.D_Fecha_Recibo = uidatepicker(app.P_TablaRecibo_Consumo_1);
            app.D_Fecha_Recibo.DisplayFormat = 'MM.uuuu';
            app.P_Botones_Recibo_1 = uipanel(app.P_Main);
            app.P_Botones_Recibo_1.Visible = 'off';
            app.B_ReciboGestionar_1 = uibutton(app.P_Botones_Recibo_1, 'push');
            app.B_ReciboGestionar_1.ButtonPushedFcn = createCallbackFcn(app, @ReciboGestionarView, true);
            app.B_ReciboGestionar_1.Text = 'Gestionar recibos';
            app.B_ReciboAnadir_1 = uibutton(app.P_Botones_Recibo_1, 'push');
            app.B_ReciboAnadir_1.ButtonPushedFcn = createCallbackFcn(app, @ReciboAnadirView, true);
            app.B_ReciboAnadir_1.Text = 'Añadir recibo';
            app.P_Tendencias_1 = uipanel(app.P_Main);
            app.P_Tendencias_1.Visible = 'off';
            app.AnlisisdeDatosLabel = uilabel(app.P_Tendencias_1);
            app.AnlisisdeDatosLabel.Text = 'Análisis de Datos';
            app.B_AnadirE_Tendencias_1 = uibutton(app.P_Tendencias_1, 'push');
            app.B_AnadirE_Tendencias_1.ButtonPushedFcn = createCallbackFcn(app, @B_Tendencias_AnadirE_View, true);
            app.B_AnadirE_Tendencias_1.Text = 'Añadir electrodoméstico';
            app.B_EditarE_Tendencia_1 = uibutton(app.P_Tendencias_1, 'push');
            app.B_EditarE_Tendencia_1.ButtonPushedFcn = createCallbackFcn(app, @B_Tendencias_ModificarE_View, true);
            app.B_EditarE_Tendencia_1.Text = 'Editar electrodoméstico';
            app.B_AnadirRec_Tendencias_1 = uibutton(app.P_Tendencias_1, 'push');
            app.B_AnadirRec_Tendencias_1.ButtonPushedFcn = createCallbackFcn(app, @B_Tendencias_AnadirRec_View, true);
            app.B_AnadirRec_Tendencias_1.Text = 'Añadir recibo';
            app.B_EditarRec_Tendencias_1 = uibutton(app.P_Tendencias_1, 'push');
            app.B_EditarRec_Tendencias_1.ButtonPushedFcn = createCallbackFcn(app, @B_Tendencias_ModificarRec_View, true);
            app.B_EditarRec_Tendencias_1.Text = 'Editar recibos';
            app.P_TendenciaProduccion_1 = uipanel(app.P_Tendencias_1);
            app.G_PvsT_Tendencias_1 = uiaxes(app.P_TendenciaProduccion_1);
            title(app.G_PvsT_Tendencias_1, 'Energía producción vs tiempo')
            xlabel(app.G_PvsT_Tendencias_1, 'X')
            ylabel(app.G_PvsT_Tendencias_1, 'Y')
            zlabel(app.G_PvsT_Tendencias_1, 'Z')
            app.TA_DescripcionAnalisisPvsT_Tendencias_1 = uitextarea(app.P_TendenciaProduccion_1);
            app.P_TendenciaAnalisisRadTemp_1 = uipanel(app.P_Tendencias_1);
            app.G_RadTemp_Tendencias_1 = uiaxes(app.P_TendenciaAnalisisRadTemp_1);
            title(app.G_RadTemp_Tendencias_1, 'LALAL')
            xlabel(app.G_RadTemp_Tendencias_1, 'X')
            ylabel(app.G_RadTemp_Tendencias_1, 'Y')
            zlabel(app.G_RadTemp_Tendencias_1, 'Z')
            app.G_RadTemp_Tendencias_2 = uiaxes(app.P_TendenciaAnalisisRadTemp_1);
            title(app.G_RadTemp_Tendencias_2, 'LALAL')
            xlabel(app.G_RadTemp_Tendencias_2, 'X')
            ylabel(app.G_RadTemp_Tendencias_2, 'Y')
            zlabel(app.G_RadTemp_Tendencias_2, 'Z')
            app.G_RadTemp_Tendencias_2.Visible = 'off';
            app.TA_DescripcionAnalisisRadTemp_Tendencias_1 = uitextarea(app.P_TendenciaAnalisisRadTemp_1);
            app.B_Tendencia_CambiarGraf_1 = uibutton(app.P_TendenciaAnalisisRadTemp_1, 'state');
            app.B_Tendencia_CambiarGraf_1.ValueChangedFcn = createCallbackFcn(app, @B_Tendencia_CambiarGraf_1ValueChanged, true);
            app.B_Tendencia_CambiarGraf_1.Icon = 'icons10.png';
            app.B_Tendencia_CambiarGraf_1.Text = '';
            app.P_TendenciaCostoMeses_1 = uipanel(app.P_Tendencias_1);
            app.G_CostoMeses_Tendencias_1 = uiaxes(app.P_TendenciaCostoMeses_1);
            title(app.G_CostoMeses_Tendencias_1, 'ANALISIS DEL COSTO KWH ')
            xlabel(app.G_CostoMeses_Tendencias_1, 'X')
            ylabel(app.G_CostoMeses_Tendencias_1, 'Y')
            zlabel(app.G_CostoMeses_Tendencias_1, 'Z')
            app.TA_DescripcionAnalisisCostoMeses_Tendencias_1 = uitextarea(app.P_TendenciaCostoMeses_1);
            app.L_DescripcionRadTemp = uilabel(app.P_Main);
            app.L_DescripcionRadTemp.Text = 'Descripción de análisis';
            app.P_Botones_Tendencias_1 = uipanel(app.P_Main);
            app.P_Botones_Tendencias_1.Visible = 'off';
            app.B_DatosClimaticos_1 = uibutton(app.P_Botones_Tendencias_1, 'push');
            app.B_DatosClimaticos_1.ButtonPushedFcn = createCallbackFcn(app, @B_Tendencias_CostoKWH_View, true);
            app.B_DatosClimaticos_1.Text = 'Costo KWH';
            app.B_TendenciasCostoKWH_1 = uibutton(app.P_Botones_Tendencias_1, 'push');
            app.B_TendenciasCostoKWH_1.ButtonPushedFcn = createCallbackFcn(app, @B_Tendencias_Consumo_View, true);
            app.B_TendenciasCostoKWH_1.Text = 'Consumo';
            app.B_TendenciasConsumo_1 = uibutton(app.P_Botones_Tendencias_1, 'push');
            app.B_TendenciasConsumo_1.ButtonPushedFcn = createCallbackFcn(app, @B_Tendencias_DatosClimaticos_View, true);
            app.B_TendenciasConsumo_1.Text = 'Datos climáticos';
            app.G_Estadistica = uipanel(app.P_Main);
            app.G_Estadistica.Visible = 'off';
            app.G_Estadistica_Recibo = uiaxes(app.G_Estadistica);
            title(app.G_Estadistica_Recibo, 'Title')
            xlabel(app.G_Estadistica_Recibo, 'X')
            ylabel(app.G_Estadistica_Recibo, 'Y')
            zlabel(app.G_Estadistica_Recibo, 'Z')
            app.ElectrodomesticosButton = uibutton(app.G_Estadistica, 'push');
            app.ElectrodomesticosButton.Text = 'Electrodomesticos';
            app.RecibosButton = uibutton(app.G_Estadistica, 'push');
            app.RecibosButton.Text = 'Recibos';
            app.A_Year_Estadistica = uidropdown(app.G_Estadistica);
            app.A_Year_Estadistica.ValueChangedFcn = createCallbackFcn(app, @I_ReciboImageClicked, true);
            % Show the figure after all components are created
            app.P_Main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.P_Main)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.P_Main)
        end
    end
end